classdef T1mappD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        T1mappUIFigure              matlab.ui.Figure
        Hyperlink                   matlab.ui.control.Hyperlink
        FitResultsTabGroup          matlab.ui.container.TabGroup
        FitResultsTab               matlab.ui.container.Tab
        FitEquationLabel            matlab.ui.control.Label
        FittedT1Label               matlab.ui.control.Label
        DESPOT1Label                matlab.ui.control.Label
        SignalFlipAngleLabel        matlab.ui.control.Label
        SignalFunctionImage         matlab.ui.control.Image
        T1Label                     matlab.ui.control.Label
        M0Label                     matlab.ui.control.Label
        T1XLabel                    matlab.ui.control.Label
        T1YLabel                    matlab.ui.control.Label
        M0XLabel                    matlab.ui.control.Label
        M0YLabel                    matlab.ui.control.Label
        T1image                     matlab.ui.control.Image
        M0image                     matlab.ui.control.Image
        ROIPanel                    matlab.ui.container.Panel
        BrushEditFieldLabel         matlab.ui.control.Label
        BrushEditField              matlab.ui.control.NumericEditField
        ROIbutton                   matlab.ui.control.Button
        EraseButton                 matlab.ui.control.Button
        ColormapButtonGroup         matlab.ui.container.ButtonGroup
        AutoButton                  matlab.ui.control.Button
        MaxT1EditFieldLabel         matlab.ui.control.Label
        MaxT1EditField              matlab.ui.control.NumericEditField
        lipariButton                matlab.ui.control.RadioButton
        parulaButton                matlab.ui.control.RadioButton
        winterButton                matlab.ui.control.RadioButton
        autumnButton                matlab.ui.control.RadioButton
        grayButton                  matlab.ui.control.RadioButton
        FitImageSelectionPanel      matlab.ui.container.Panel
        FitOrientationSpinnerLabel  matlab.ui.control.Label
        FitFrameEditFieldLabel      matlab.ui.control.Label
        FitSliceEditFieldLabel      matlab.ui.control.Label
        FitOrientationSpinner       matlab.ui.control.Spinner
        FitFrameSlider              matlab.ui.control.Slider
        FitFrameEditField           matlab.ui.control.NumericEditField
        FitSliceSlider              matlab.ui.control.Slider
        FitSliceEditField           matlab.ui.control.NumericEditField
        M0map                       matlab.ui.control.UIAxes
        T1map                       matlab.ui.control.UIAxes
        DespotT1FitFig              matlab.ui.control.UIAxes
        T1FitFig                    matlab.ui.control.UIAxes
        ECVTab                      matlab.ui.container.Tab
        T1postBloodEditField        matlab.ui.control.NumericEditField
        T1postBloodEditFieldLabel   matlab.ui.control.Label
        T1preBloodEditField         matlab.ui.control.NumericEditField
        T1preBloodEditFieldLabel    matlab.ui.control.Label
        ECVTransImage               matlab.ui.control.Image
        PostGdTransImage            matlab.ui.control.Image
        PreGdTransImage             matlab.ui.control.Image
        ECVExportButton             matlab.ui.control.Button
        ECVRegistrationDropDown     matlab.ui.control.DropDown
        ECVRegisterButton           matlab.ui.control.Button
        ECVLoadPostGdButton         matlab.ui.control.Button
        ECVLoadPreGdButton          matlab.ui.control.Button
        ECVImageSelectionPanel      matlab.ui.container.Panel
        ECVFrameEditFieldLabel      matlab.ui.control.Label
        ECVSliceEditFieldLabel      matlab.ui.control.Label
        ECVOrientationSpinnerLabel  matlab.ui.control.Label
        ECVOrientationSpinner       matlab.ui.control.Spinner
        ECVFrameSlider              matlab.ui.control.Slider
        ECVFrameEditField           matlab.ui.control.NumericEditField
        ECVSliceSlider              matlab.ui.control.Slider
        ECVSliceEditField           matlab.ui.control.NumericEditField
        ECVColormapButtonGroup      matlab.ui.container.ButtonGroup
        ECVlipariButton             matlab.ui.control.RadioButton
        ECVparulaButton             matlab.ui.control.RadioButton
        ECVwinterButton             matlab.ui.control.RadioButton
        ECVautumnButton             matlab.ui.control.RadioButton
        ECVgrayButton               matlab.ui.control.RadioButton
        ECVBloodROIPanel            matlab.ui.container.Panel
        ECVBrushEditFieldLabel      matlab.ui.control.Label
        ECVBrushEditField           matlab.ui.control.NumericEditField
        ECVEraseButton              matlab.ui.control.Button
        ECVYLabel                   matlab.ui.control.Label
        ECVXLabel                   matlab.ui.control.Label
        ECVLabel                    matlab.ui.control.Label
        PreGdXLabel                 matlab.ui.control.Label
        PreGdYLabel                 matlab.ui.control.Label
        PreGdLabel                  matlab.ui.control.Label
        HematocritEditField         matlab.ui.control.NumericEditField
        HematocritEditFieldLabel    matlab.ui.control.Label
        PostGdXLabel                matlab.ui.control.Label
        PostGdYLabel                matlab.ui.control.Label
        PostGdLabel                 matlab.ui.control.Label
        ECVmap                      matlab.ui.control.UIAxes
        PostGdmap                   matlab.ui.control.UIAxes
        PreGdmap                    matlab.ui.control.UIAxes
        RawImagesTabGroup           matlab.ui.container.TabGroup
        RawimagesTab                matlab.ui.container.Tab
        AutoLWButton                matlab.ui.control.StateButton
        LevelEditField              matlab.ui.control.NumericEditField
        LevelEditFieldLabel         matlab.ui.control.Label
        LevelSlider                 matlab.ui.control.Slider
        WindowEditField             matlab.ui.control.NumericEditField
        WindowEditFieldLabel        matlab.ui.control.Label
        WindowSlider                matlab.ui.control.Slider
        Image0                      matlab.ui.control.Image
        YLabel                      matlab.ui.control.Label
        XLabel                      matlab.ui.control.Label
        ImageLabel                  matlab.ui.control.Label
        MaskSettingsPanel           matlab.ui.container.Panel
        OpenButton                  matlab.ui.control.Button
        CloseButton                 matlab.ui.control.Button
        DilateButton                matlab.ui.control.Button
        ErodeButton                 matlab.ui.control.Button
        ThresholdSlider             matlab.ui.control.Slider
        ThresholdEditField          matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel     matlab.ui.control.Label
        ImageSelectionPanel         matlab.ui.container.Panel
        OrientationSpinnerLabel     matlab.ui.control.Label
        FrameEditFieldLabel         matlab.ui.control.Label
        FlipAngleEditFieldLabel     matlab.ui.control.Label
        SliceEditFieldLabel         matlab.ui.control.Label
        OrientationSpinner          matlab.ui.control.Spinner
        FrameSlider                 matlab.ui.control.Slider
        FrameEditField              matlab.ui.control.NumericEditField
        FlipAngleSlider             matlab.ui.control.Slider
        FlipAngleEditField          matlab.ui.control.NumericEditField
        SliceSlider                 matlab.ui.control.Slider
        SliceEditField              matlab.ui.control.NumericEditField
        RawImage                    matlab.ui.control.UIAxes
        B1Tab                       matlab.ui.container.Tab
        B1Image                     matlab.ui.control.Image
        B1XLabel                    matlab.ui.control.Label
        B1YLabel                    matlab.ui.control.Label
        B1OrientationSpinner        matlab.ui.control.Spinner
        B1OrientationSpinnerLabel   matlab.ui.control.Label
        B1MapCheckBox               matlab.ui.control.CheckBox
        B1LoadMRDButton             matlab.ui.control.Button
        B1SliceEditFieldLabel       matlab.ui.control.Label
        B1SliceSlider               matlab.ui.control.Slider
        B1SliceEditField            matlab.ui.control.NumericEditField
        B1map                       matlab.ui.control.UIAxes
        AppSettingsTab              matlab.ui.container.Tab
        SoundsPanel                 matlab.ui.container.Panel
        AutoSoundsCheckBoxLabel     matlab.ui.control.Label
        AutoSoundsCheckBox          matlab.ui.control.CheckBox
        ColorsPanel                 matlab.ui.container.Panel
        ColorsButtonGroup           matlab.ui.container.ButtonGroup
        BlackWhiteButton            matlab.ui.control.RadioButton
        ClassicButton               matlab.ui.control.RadioButton
        SequenceParametersTabGroup  matlab.ui.container.TabGroup
        SequenceParametersTab       matlab.ui.container.Tab
        FAsIncludedViewFieldLabel   matlab.ui.control.Label
        FAsIncludedViewField        matlab.ui.control.EditField
        FAsViewFieldLabel           matlab.ui.control.Label
        FAsViewField                matlab.ui.control.EditField
        TEViewFieldLabel            matlab.ui.control.Label
        TEViewField                 matlab.ui.control.NumericEditField
        TRViewFieldLabel            matlab.ui.control.Label
        TRViewField                 matlab.ui.control.NumericEditField
        SequenceViewFieldLabel      matlab.ui.control.Label
        SequenceViewField           matlab.ui.control.EditField
        ZLabelFOV                   matlab.ui.control.Label
        YLabelFOV                   matlab.ui.control.Label
        XLabelFOV                   matlab.ui.control.Label
        FOVViewFieldLabel           matlab.ui.control.Label
        FOVViewField3               matlab.ui.control.NumericEditField
        FOVViewField2               matlab.ui.control.NumericEditField
        FOVViewField1               matlab.ui.control.NumericEditField
        MatrixViewFieldLabel        matlab.ui.control.Label
        MatrixViewField3            matlab.ui.control.NumericEditField
        MatrixViewField2            matlab.ui.control.NumericEditField
        MatrixViewField1            matlab.ui.control.NumericEditField
        MessagesTabGroup            matlab.ui.container.TabGroup
        MessagesTab                 matlab.ui.container.Tab
        StatusLabel                 matlab.ui.control.Label
        StatusImage                 matlab.ui.control.Image
        BackgroundBox               matlab.ui.control.TextArea
        MessageBox                  matlab.ui.control.TextArea
        DashboardTabGroup           matlab.ui.container.TabGroup
        DashboardTab                matlab.ui.container.Tab
        EstimatedRegTimeViewField   matlab.ui.control.EditField
        AbortRegButton              matlab.ui.control.Button
        RegistrationDropDown        matlab.ui.control.DropDown
        EstimatedFitTimeViewField   matlab.ui.control.EditField
        Lamp7                       matlab.ui.control.Lamp
        Lamp6                       matlab.ui.control.Lamp
        Lamp5                       matlab.ui.control.Lamp
        Lamp4                       matlab.ui.control.Lamp
        Lamp3                       matlab.ui.control.Lamp
        Lamp2                       matlab.ui.control.Lamp
        Lamp1                       matlab.ui.control.Lamp
        AbortFitButton              matlab.ui.control.Button
        FitProgressGauge            matlab.ui.control.LinearGauge
        ExitButton                  matlab.ui.control.Button
        GIFButton                   matlab.ui.control.Button
        ExportNIFTIButton           matlab.ui.control.Button
        DCMNIFTIButton              matlab.ui.control.Button
        FitT1Button                 matlab.ui.control.Button
        RegistrationProgressGauge   matlab.ui.control.LinearGauge
        MaskButton                  matlab.ui.control.Button
        RegisterButton              matlab.ui.control.Button
        LoadMRDButton               matlab.ui.control.Button
        MRSImage                    matlab.ui.control.Image
        TulpImage                   matlab.ui.control.Image
        CreditsLabel                matlab.ui.control.Label
        TitleLabel                  matlab.ui.control.Label
    end


    %% ----------------------------------------------------------------------------
    %
    %   3D T1 mapping 
    %
    %   Gustav Strijkers
    %   g.j.strijkers@amsterdamumc.nl 
    %   Amsterdam UMC
    %
    %   July 2025
    %
    %
    %   Version 1.0
    %   - initial version 
    %
    %   Version 2.0
    %   - app overhaul
    %   - added mulitple echo times, for cardiac T1 mapping
    %
    %   Version 2.1
    %   - cross-hair and ROI T1 value display
    %   - export bug fixes
    %
    %   Version 2.2
    %   - B1 map correction
    %
    %   Version 2.3
    %   - Improved ROI editing
    %
    %   Version 3
    %   - General update and maintenance
    %   - Dicom export bug fix
    %   - Export automatically to GIF, Nift, and DICOM folders if available
    %   - Allows for import of 2D data
    %   - Allows for import of multi-coil data
    %   - Color schemes
    %   - Better version check
    %
    %   Version 3.7
    %   - General update, Matlab 2025a
    %
    %   Version 3.8 
    %   - ECV calculation
    %   - lipari color map
    %
    %
    %% ----------------------------------------------------------------------------
    
    %#ok<*ST2NM> 
    
    properties (Access = public)

        % App version
        appVersion = "3.8.5"        % App version
        bartVersion                 % Bart version
        dataType = 'm'              % MR Solutions data
        
        % File parameters
        mrdImportFolder             % mrd import folder
        dataImportFolder            % data import folder
        dcmExportFolder             % dicom export folder
        exportFolder                % dcm/nifti export folder
        niftiExportFolder           % nifti export folder
        gifExportFolder             % gif export folder
        appFolder                   % application folder
        oldFolder                   % starting folder
        
        % Sequence parameters
        SEQparameters               % sequence parameters
        ECVparameters               % ECV sequence parameters
        B1Parameters                % B1 sequence parameters
        B1FlipAngles                % B1 flip angle list
        FAsIncluded                 % flip angles included in the fit
        
        % Images
        image5D                     % image data (flipangles, echoes/frames, x , y , z);
        imageT1                     % 3D T1 map (frames, x, y, z)
        imageM0                     % 3D M0 map (frames, x, y, z)
        image4D                     % B1 image data (flipangles, x, y, z);
        imageB1                     % 3D B1 map (x, y, z)
        imagePreGd                  % 3D pre Gd contrast T1 and M0 maps (frames, x, y, z, 1=T1 / 2=M0)
        imagePostGd                 % 3D post Gd contrast T1 and M0 maps (frames, x, y, z, 1=T1 / 2=M0)
        imageECV                    % 3D ECV maps (x, y, z)
        imageGdInfo                 % Nifti info     

        % Flags
        validDataFileFlag           % valid data file true or false
        validB1Flag                 % valid B1 correction data file true or false
        validRecoFlag               % valid reconstruction true or false
        validFitFlag                % valid fit data true or false
        validMaskFlag               % valid mask true or false
        bartDetectedFlag            % bart toolbox detected true or false
        abortFitFlag                % abort fit true or false
        abortRegFlag                % abort registration true or false
        validRegFlag                % valid registration
        mrdFlag                     % search for system MRD folder true or false
        roiFlag                     % roi drawing true or false
        validPreGdFlag              % valid pre Gd T1 maps
        validPostGdFlag             % valid post Gd T1 maps
        validECVmapFlag             % valid ECV map available
        
        % Plot parameters
        t1Cmap                      % T1 color map
        ECVCmap                     % ECV images color  map
        flipAngles                  % flip angles
        T1ImageOrient               % image orientations
        B1ImageOrient               % B1 image orientations
        T1ImageOrientLabel          % image orientation text labels
        maskImageOrient             % mask orientation
        ECVImageOrient              % ECV image orientation
        threshold                   % image threshold for segmentation
        mask                        % image mask for segmentation
        maxIndx                     % number of the flip angle image with highest intensity, used for segmentation
        T1cross                     % T1 cross-hair
        T1roiPoints                 % T1 roi point coordinates
        T1roi                       % T1 roi drawing object
        M0cross                     % M0 cross-hair
        M0roi                       % M0 roi drawing object 
        T1cbar                      % T1 map color bar
        ECVroiPoints                % ECV roi point coordinates
        ECVPreRoi                   % ECV roi drawing object in pre Gd image
        ECVPostRoi                  % ECV roi drawing object in post Gd image
        ECVcbar1                    % Color bar in Gd pre image
        ECVcbar2                    % Color bar in Gd post image
        ECVcbar3                    % Color bar in ECV image
        structuringElement          % 3D structuring element
     
        % Colors
        green                       % other shade of green
        brightgreen                 % green
        red                         % other shade of red
        brightred                   % red
        blue                        % blue
        yellow                      % yellow
        orange                      % orange
        black                       % black
        lightblue                   % lightblue
        grey                        % grey
        guiBlack                    % app black
        guiGrey                     % app grey
        guiMiddleGrey               % app middle grey
        guiDarkGrey                 % app dark grey
        guiWhite                    % app white
        guiBlackBlue                % app black/blueish

        % Sounds
        warningSound                % warning sound
        errorSound                  % error sound
           
    end
    
   
    methods (Access = private)


        % -------------------------------------------------
        % Buttons and lights controls
        % -------------------------------------------------
        
        
        function SetLights(app,code)
            
            if ~strcmp(code{1},'Null') app.Lamp1.Color = code{1}; end %#ok<*SEPEX> 
            if ~strcmp(code{2},'Null') app.Lamp2.Color = code{2}; end
            if ~strcmp(code{3},'Null') app.Lamp3.Color = code{3}; end
            if ~strcmp(code{4},'Null') app.Lamp4.Color = code{4}; end
            if ~strcmp(code{5},'Null') app.Lamp5.Color = code{5}; end
            if ~strcmp(code{6},'Null') app.Lamp6.Color = code{6}; end
            if ~strcmp(code{7},'Null') app.Lamp7.Color = code{7}; end
            drawnow;
            
        end
        
        
        function result = GetLights(app)
            
            result{1} = app.Lamp1.Color;
            result{2} = app.Lamp2.Color;
            result{3} = app.Lamp3.Color;
            result{4} = app.Lamp4.Color;
            result{5} = app.Lamp5.Color;
            result{6} = app.Lamp6.Color;
            result{7} = app.Lamp7.Color;
            
        end
        
        
        function SetButtons(app,code)
            
            if ~strcmp(code{1},'Null') app.LoadMRDButton.Enable = code{1}; end
            if ~strcmp(code{2},'Null') app.RegisterButton.Enable = code{2}; app.RegistrationDropDown.Enable = code{2}; end
            if ~strcmp(code{3},'Null') app.MaskButton.Enable = code{3}; end
            if ~strcmp(code{4},'Null') app.FitT1Button.Enable = code{4}; end
            if ~strcmp(code{5},'Null') app.DCMNIFTIButton.Enable = code{5}; end
            if ~strcmp(code{6},'Null') app.ExportNIFTIButton.Enable = code{6}; end
            if ~strcmp(code{7},'Null') app.GIFButton.Enable = code{7}; end
            if ~strcmp(code{8},'Null') app.ExitButton.Enable = code{8}; end
            drawnow;
            
        end
        
        
        function result = GetButtons(app)
            
            result{1} = app.LoadMRDButton.Enable;
            result{2} = app.RegisterButton.Enable;
            result{3} = app.MaskButton.Enable;
            result{4} = app.FitT1Button.Enable;
            result{5} = app.DCMNIFTIButton.Enable;
            result{6} = app.ExportNIFTIButton.Enable;
            result{7} = app.GIFButton.Enable;
            result{8} = app.ExitButton.Enable;
            
        end
        
        
        function DisableSegmentButtons(app)
            
            app.MaskButton.Enable = 'off';
            app.ThresholdEditField.Enable = 'off';
            app.ThresholdEditFieldLabel.Enable = 'off';
            app.ThresholdSlider.Enable = 'off';
            app.ErodeButton.Enable = 'off';
            app.DilateButton.Enable = 'off';
            app.CloseButton.Enable = 'off';
            app.OpenButton.Enable = 'off';
            drawnow;
            
        end
        
        
        function EnableSegmentButtons(app)
            
            app.MaskButton.Enable = 'on';
            app.ThresholdEditField.Enable = 'on';
            app.ThresholdEditFieldLabel.Enable = 'on';
            app.ThresholdSlider.Enable = 'on';
            app.ErodeButton.Enable = 'on';
            app.DilateButton.Enable = 'on';
            app.CloseButton.Enable = 'on';
            app.OpenButton.Enable = 'on';
            drawnow;
            
        end


        function DisableECVbuttons(app)

            app.ECVLoadPreGdButton.Enable = 'off';
            app.ECVLoadPostGdButton.Enable = 'off';
            app.ECVExportButton.Enable = 'off';
            app.HematocritEditField.Enable = 'off';
            app.HematocritEditFieldLabel.Enable = 'off';
            app.T1preBloodEditField.Enable = 'off';
            app.T1preBloodEditFieldLabel.Enable = 'off';
            app.T1postBloodEditField.Enable = 'off';
            app.T1postBloodEditFieldLabel.Enable = 'off';
            app.ECVEraseButton.Enable = 'off';
            app.ECVBrushEditField.Enable = 'off';
            app.ECVBrushEditFieldLabel.Enable = 'off';
            app.ECVRegisterButton.Enable = 'off';
            app.ECVRegistrationDropDown.Enable = 'off';
            app.ECVSliceEditField.Enable = 'off';
            app.ECVSliceEditFieldLabel.Enable = 'off';
            app.ECVSliceSlider.Enable = 'off';
            app.ECVFrameEditField.Enable = 'off';
            app.ECVFrameEditFieldLabel.Enable = 'off';
            app.ECVFrameSlider.Enable = 'off';
            app.ECVOrientationSpinner.Enable = 'off';
            app.ECVOrientationSpinnerLabel.Enable = 'off';
            app.ECVlipariButton.Enable = 'off';
            app.ECVparulaButton.Enable = 'off';
            app.ECVwinterButton.Enable = 'off';
            app.ECVautumnButton.Enable = 'off';
            app.ECVgrayButton.Enable = 'off';

        end


        function EnableECVbuttons(app)

            app.ECVLoadPreGdButton.Enable = 'on';
            app.ECVLoadPostGdButton.Enable = 'on';
            app.ECVExportButton.Enable = 'on';
            app.HematocritEditField.Enable = 'on';
            app.HematocritEditFieldLabel.Enable = 'on';
            app.T1preBloodEditField.Enable = 'on';
            app.T1preBloodEditFieldLabel.Enable = 'on';
            app.T1postBloodEditField.Enable = 'on';
            app.T1postBloodEditFieldLabel.Enable = 'on';
            app.ECVEraseButton.Enable = 'on';
            app.ECVBrushEditField.Enable = 'on';
            app.ECVBrushEditFieldLabel.Enable = 'on';
            app.ECVRegisterButton.Enable = 'on';
            app.ECVRegistrationDropDown.Enable = 'on';

            if (app.validPreGdFlag && app.ECVparameters.threeDdataPre) || (app.validPostGdFlag && app.ECVparameters.threeDdataPost)
                app.ECVSliceEditField.Enable = 'on';
                app.ECVSliceEditFieldLabel.Enable = 'on';
                app.ECVSliceSlider.Enable = 'on';
                app.ECVOrientationSpinner.Enable = 'on';
                app.ECVOrientationSpinnerLabel.Enable = 'on';
            else
                app.ECVSliceEditField.Enable = 'off';
                app.ECVSliceEditFieldLabel.Enable = 'off';
                app.ECVSliceSlider.Enable = 'off';
                app.ECVOrientationSpinner.Enable = 'off';
                app.ECVOrientationSpinnerLabel.Enable = 'off';
            end

            if (app.validPreGdFlag && app.ECVparameters.multiFramePre) || (app.validPostGdFlag && app.ECVparameters.multiFramePost)
                app.ECVFrameEditField.Enable = 'on';
                app.ECVFrameEditFieldLabel.Enable = 'on';
                app.ECVFrameSlider.Enable = 'on';
            else
                app.ECVFrameEditField.Enable = 'off';
                app.ECVFrameEditFieldLabel.Enable = 'off';
                app.ECVFrameSlider.Enable = 'off';
            end

            app.ECVlipariButton.Enable = 'on';
            app.ECVparulaButton.Enable = 'on';
            app.ECVwinterButton.Enable = 'on';
            app.ECVautumnButton.Enable = 'on';
            app.ECVgrayButton.Enable = 'on';

        end


        % Set application colors
        function SetAppColorsFcn(app, guiColors)

            backgroundColor = guiColors{1};
            fontColor = guiColors{2};
            %boxColor = guiColors{3};
            gaugeColor = guiColors{4};
            gaugeBackgroundColor = guiColors{5};
            figureColor = guiColors{6};
            movieFigureColor = guiColors{7};
            gridColor = guiColors{8};

            % Panels
            app.T1mappUIFigure.Color = backgroundColor;
            app.DashboardTab.BackgroundColor = backgroundColor;
            app.RawimagesTab.BackgroundColor = backgroundColor;
            app.B1Tab.BackgroundColor = backgroundColor;
            app.AppSettingsTab.BackgroundColor = backgroundColor;
            app.SequenceParametersTab.BackgroundColor = backgroundColor;
            app.MessagesTab.BackgroundColor = backgroundColor;
            app.FitResultsTab.BackgroundColor = backgroundColor;
            app.ImageSelectionPanel.BackgroundColor = backgroundColor;
            app.MaskSettingsPanel.BackgroundColor = backgroundColor;
            app.ColorsButtonGroup.BackgroundColor = backgroundColor;
            app.FitImageSelectionPanel.BackgroundColor = backgroundColor;
            app.ColormapButtonGroup.BackgroundColor = backgroundColor;
            app.ROIPanel.BackgroundColor = backgroundColor;
            app.SoundsPanel.BackgroundColor = backgroundColor;
            app.ColorsPanel.BackgroundColor = backgroundColor;
            app.ECVTab.BackgroundColor = backgroundColor;
            app.ECVBloodROIPanel.BackgroundColor = backgroundColor;
            app.ECVImageSelectionPanel.BackgroundColor = backgroundColor;
            app.ECVColormapButtonGroup.BackgroundColor = backgroundColor;

            % Gauges
            app.RegistrationProgressGauge.FontColor = gaugeColor;
            app.RegistrationProgressGauge.BackgroundColor = gaugeBackgroundColor;
            app.FitProgressGauge.FontColor = gaugeColor;
            app.FitProgressGauge.BackgroundColor = gaugeBackgroundColor;

            % Text
            app.SliceEditFieldLabel.FontColor = fontColor;
            app.FlipAngleEditFieldLabel.FontColor = fontColor;
            app.FrameEditFieldLabel.FontColor = fontColor;
            app.OrientationSpinnerLabel.FontColor = fontColor;
            app.ThresholdEditFieldLabel.FontColor = fontColor;
            app.YLabel.FontColor = fontColor;
            app.XLabel.FontColor = fontColor;
            app.B1YLabel.FontColor = fontColor;
            app.B1XLabel.FontColor = fontColor;
            app.ImageLabel.FontColor = fontColor;
            app.WindowEditFieldLabel.FontColor = fontColor;
            app.LevelEditFieldLabel.FontColor = fontColor;
            app.B1SliceEditFieldLabel.FontColor = fontColor;
            app.B1OrientationSpinnerLabel.FontColor = fontColor;
            app.B1MapCheckBox.FontColor = fontColor;
            app.AutoSoundsCheckBoxLabel.FontColor = fontColor;
            app.BlackWhiteButton.FontColor = fontColor;
            app.ClassicButton.FontColor = fontColor;
            app.SequenceViewFieldLabel.FontColor = fontColor;
            app.TRViewFieldLabel.FontColor = fontColor;
            app.TEViewFieldLabel.FontColor = fontColor;
            app.MatrixViewFieldLabel.FontColor = fontColor;
            app.FOVViewFieldLabel.FontColor = fontColor;
            app.XLabelFOV.FontColor = fontColor;
            app.YLabelFOV.FontColor = fontColor;
            app.ZLabelFOV.FontColor = fontColor;
            app.FAsViewFieldLabel.FontColor = fontColor;
            app.FAsIncludedViewFieldLabel.FontColor = fontColor;
            app.T1Label.FontColor = fontColor;
            app.M0Label.FontColor = fontColor;
            app.T1XLabel.FontColor = fontColor;
            app.M0XLabel.FontColor = fontColor;
            app.T1YLabel.FontColor = fontColor;
            app.M0YLabel.FontColor = fontColor;
            app.FitSliceEditFieldLabel.FontColor = fontColor;
            app.FitFrameEditFieldLabel.FontColor = fontColor;
            app.FitOrientationSpinnerLabel.FontColor = fontColor;
            app.MaxT1EditFieldLabel.FontColor = fontColor;
            app.grayButton.FontColor = fontColor;
            app.autumnButton.FontColor = fontColor;
            app.winterButton.FontColor = fontColor;
            app.parulaButton.FontColor = fontColor;
            app.lipariButton.FontColor = fontColor;
            app.BrushEditFieldLabel.FontColor = fontColor;
            app.SignalFlipAngleLabel.FontColor = fontColor;
            app.DESPOT1Label.FontColor = fontColor;
            app.FitEquationLabel.FontColor = fontColor;
            app.PreGdLabel.FontColor = fontColor;
            app.PreGdXLabel.FontColor = fontColor;
            app.PreGdYLabel.FontColor = fontColor;
            app.PostGdLabel.FontColor = fontColor;
            app.PostGdXLabel.FontColor = fontColor;
            app.PostGdYLabel.FontColor = fontColor;
            app.ECVLabel.FontColor = fontColor;
            app.ECVXLabel.FontColor = fontColor;
            app.ECVYLabel.FontColor = fontColor;
            app.HematocritEditFieldLabel.FontColor = fontColor;
            app.T1preBloodEditFieldLabel.FontColor = fontColor;
            app.T1postBloodEditFieldLabel.FontColor = fontColor;
            app.ECVSliceEditFieldLabel.FontColor = fontColor;
            app.ECVFrameEditFieldLabel.FontColor = fontColor;
            app.ECVOrientationSpinnerLabel.FontColor = fontColor;
            app.ECVgrayButton.FontColor = fontColor;
            app.ECVautumnButton.FontColor = fontColor;
            app.ECVwinterButton.FontColor = fontColor;
            app.ECVparulaButton.FontColor = fontColor;
            app.ECVlipariButton.FontColor = fontColor;
            app.ECVBrushEditFieldLabel.FontColor = fontColor;

            % Figures
            app.RawImage.Color = movieFigureColor;
            app.RawImage.XColor = movieFigureColor;
            app.RawImage.YColor = movieFigureColor;

            app.B1map.Color = movieFigureColor;
            app.B1map.XColor = movieFigureColor;
            app.B1map.YColor = movieFigureColor;

            app.T1map.Color = movieFigureColor;
            app.T1map.XColor = movieFigureColor;
            app.T1map.YColor = movieFigureColor;

            app.M0map.Color = movieFigureColor;
            app.M0map.XColor = movieFigureColor;
            app.M0map.YColor = movieFigureColor;

            app.T1FitFig.Color = figureColor;
            app.T1FitFig.XColor = fontColor;
            app.T1FitFig.YColor = fontColor;

            app.DespotT1FitFig.Color = figureColor;
            app.DespotT1FitFig.XColor = fontColor;
            app.DespotT1FitFig.YColor = fontColor;

            app.PreGdmap.Color = movieFigureColor;
            app.PreGdmap.XColor = movieFigureColor;
            app.PreGdmap.YColor = movieFigureColor;
 
            app.PostGdmap.Color = movieFigureColor;
            app.PostGdmap.XColor = movieFigureColor;
            app.PostGdmap.YColor = movieFigureColor;

            app.ECVmap.Color = movieFigureColor;
            app.ECVmap.XColor = movieFigureColor;
            app.ECVmap.YColor = movieFigureColor;

            app.SignalFunctionImage.BackgroundColor = gridColor;

            drawnow;

        end % SetAppColorsFcn


        % App version check
        function CheckVersionFnc(app)

            try

                currentVersion = convertStringsToChars(app.appVersion);

                newestVersions = {"3.9","3.8","3.7","3.6","3.5","3.4","3.3","3.2","3.1","3.0","2.9","2.8","2.7","2.6","2.5"};

                webtxt = [];
                ver = 0;
                while isempty(webtxt) && ver<=length(newestVersions)
                    try
                        ver = ver + 1;
                        newVersionFile = strcat("https://github.com/Moby1971/T1mappingD-v",newestVersions{ver},"/blob/master/appversion.txt");
                        webtxt = webread(newVersionFile);
                    catch
                    end
                end

                pos = strfind(lower(webtxt),lower("T1mappD_version="));
                newVersion = webtxt(pos+16:pos+16+strlength(newestVersions{ver})+1);

                app.TextMessage(strcat("Current version = ",currentVersion));
                app.TextMessage(strcat("Newest version = ",newVersion));

                newVersion = strrep(newVersion,".","");
                newVersionNr = str2num(newVersion);
                currentVersion = strrep(currentVersion,".","");
                currentVersionNr = str2num(currentVersion);

                if currentVersionNr < newVersionNr
                    app.TextMessage('A new version of the T1 mapping app is available ...');
                    app.TextMessage('Visit https://github.com/Moby1971/ to update ...');
                else
                    app.TextMessage('The T1 mapping app is up to date ...');
                end

            catch
            end

        end % CheckVersionFnc


        % Prepare the app layout
        function SetAppLayoutProperties(app)
        
            % Version
            app.CreditsLabel.Text = strcat("Gustav Strijkers, Amsterdam UMC, Version ",app.appVersion);

            % Logos
            app.TulpImage.ImageSource = 'AmsterdamUMC.png';
            app.MRSImage.ImageSource = 'MRSolutionsLogo.gif';
            app.Image0.ImageSource = 'square.gif';
            app.T1image.ImageSource = 'square.gif';
            app.M0image.ImageSource = 'square.gif';
            app.B1Image.ImageSource = 'square.gif';
            app.PreGdTransImage.ImageSource = 'square.gif';
            app.PostGdTransImage.ImageSource = 'square.gif';
            app.ECVTransImage.ImageSource = 'square.gif';
            app.SignalFunctionImage.ImageSource = 'S.gif';
            app.StatusImage.ImageSource = 'statusokay.gif';
            %app.B1map.ImageSource = 'underconstruction.gif';
            
            app.LoadMRDButton.Icon = 'disk.png';
            app.RegisterButton.Icon = 'register.png';
            app.MaskButton.Icon = 'segmentation.png';
            app.FitT1Button.Icon = 'fitting.png';
            app.DCMNIFTIButton.Icon = 'disk.png';
            app.ExportNIFTIButton.Icon = 'disk.png';
            app.GIFButton.Icon = 'disk.png';
            app.ExitButton.Icon = 'exit.png';
            app.ECVLoadPreGdButton.Icon = 'disk.png';
            app.ECVLoadPostGdButton.Icon = 'disk.png';
            app.ECVExportButton.Icon = 'disk.png';
            app.ECVRegisterButton.Icon = 'register.png';
        
            % Load sounds
            [app.warningSound,~] = audioread('Glass.aiff');
            [app.errorSound,~] = audioread('Ping.aiff');

            % Define app colors
            app.brightred = 'Red';
            app.red = '#D95319';
            app.brightgreen = 'Green'; 
            app.green = '#6CB557';
            app.yellow = 'Yellow';
            app.orange = '#FDA400';
            app.blue = '#0073BE';
            app.black = 'Black';
            app.lightblue = '#DEE6EB';
            app.grey = '#787878';
            app.guiBlack = [0.15,0.15,0.15];
            app.guiGrey = [0.94,0.94,0.94];
            app.guiMiddleGrey = [0.7,0.7,0.7];
            app.guiDarkGrey = [0.5,0.5,0.5];
            app.guiWhite = [1.0,1.0,1.0];
            app.guiBlackBlue = [0.02,0.13,0.3];
            
            % Set some plot properties
            app.RawImage.Toolbar.Visible = 'off';
            app.T1FitFig.Toolbar.Visible = 'off';
            app.DespotT1FitFig.Toolbar.Visible = 'off';
            app.T1map.Toolbar.Visible = 'off';
            app.M0map.Toolbar.Visible = 'off';
            
            % Switch off plot interactivity, except for movie plot
            app.RawImage.Interactions = [];
            app.T1FitFig.Interactions = [];
            app.DespotT1FitFig.Interactions = [];
            app.T1map.Interactions = [];
            app.M0map.Interactions = [];

            % Image orientation labels
            app.T1ImageOrient = [1 2 3 4 5; 1 2 4 5 3 ; 1 2 5 3 4 ];
            app.T1ImageOrientLabel = ['X' 'Y' 'Z' ; 'Y' 'Z' 'X' ; 'Z' 'X' 'Y'];
            app.maskImageOrient = [1 2 3 4; 1 3 4 2 ; 1 4 2 3 ];
            app.B1ImageOrient = [1 2 3; 2 3 1 ; 3 1 2];
            app.ECVImageOrient = [1 2 3; 2 3 1 ; 3 1 2];

            % All buttons off
            app.SetButtons({'off','off','off','off','off','off','off','off'});
                      
            drawnow;
            
        end


        % ------------------------------------------
        % Image acquisition data parameters
        % ------------------------------------------
        
        
        function SetParametersFcn(app)
            
            % Sequence parameters
            app.SEQparameters.matrix = [size(app.image5D,3),size(app.image5D,4),size(app.image5D,5)];
            aratio = app.SEQparameters.FOVf/8;
            app.SEQparameters.FOV3D = [app.SEQparameters.FOV,aratio*app.SEQparameters.FOV,app.SEQparameters.SLICE_THICKNESS];
            app.SEQparameters.NO_SLICES = app.SEQparameters.matrix(3);
            app.SEQparameters.SLICE_THICKNESS = app.SEQparameters.FOV3D(3)/app.SEQparameters.matrix(3);
            app.SEQparameters.SLICE_INTERLEAVE = 1;
            app.SEQparameters.SLICE_SEPARATION = app.SEQparameters.SLICE_THICKNESS;
            app.SEQparameters.SMX = app.SEQparameters.FOV3D(1)/app.SEQparameters.matrix(1);
            app.SEQparameters.SMY = app.SEQparameters.FOV3D(2)/app.SEQparameters.matrix(2);
            app.SEQparameters.FOV = app.SEQparameters.FOV3D(1);
            app.SEQparameters.NO_VIEWS = app.SEQparameters.matrix(2);
            app.SEQparameters.NO_VIEWS_2 = app.SEQparameters.matrix(3);
            app.SEQparameters.alpha = app.flipAngles(1);
            app.SEQparameters.frametime = app.SEQparameters.ti;
            
            % Show the sequence parameters in the app
            app.SequenceViewField.Value = app.SEQparameters.PPL;
            if isfield(app.SEQparameters,'tr_extra_us')
               app.SEQparameters.tr = floor(app.SEQparameters.tr) + app.SEQparameters.tr_extra_us/1000;
            end
            app.TRViewField.Value = app.SEQparameters.tr;
            app.TEViewField.Value = app.SEQparameters.te;
            app.MatrixViewField1.Value = app.SEQparameters.matrix(1);
            app.MatrixViewField2.Value = app.SEQparameters.matrix(2);
            app.MatrixViewField3.Value = app.SEQparameters.matrix(3);
            app.FOVViewField1.Value = app.SEQparameters.FOV3D(1);
            app.FOVViewField2.Value = app.SEQparameters.FOV3D(2);
            app.FOVViewField3.Value = app.SEQparameters.FOV3D(3);
            app.FAsViewField.Value = num2str(app.flipAngles);
            app.FAsIncluded = ones(length(app.flipAngles),1)';
            app.FAsIncludedViewField.Value = num2str(app.FAsIncluded);
           
            if app.SEQparameters.PHASE_ORIENTATION
                app.XLabelFOV.Text = 'Y';
                app.YLabelFOV.Text = 'X';
            else
                app.XLabelFOV.Text = 'X';
                app.YLabelFOV.Text = 'Y';
            end
            
            % Limits to some values
            app.FlipAngleEditField.Value = 1;
            app.FlipAngleEditField.Limits = [1 length(app.flipAngles)];
            app.FlipAngleSlider.Value = 1;
            app.FlipAngleSlider.Limits = [1 length(app.flipAngles)];
            if app.SEQparameters.NO_ECHOES > 1
                app.FrameEditField.Value = 1;
                app.FrameEditField.Limits = [1 app.SEQparameters.NO_ECHOES];
                app.FrameSlider.Value = 1;
                app.FrameSlider.Limits = [1 app.SEQparameters.NO_ECHOES];
                app.FrameEditField.Enable = 'on';
                app.FrameSlider.Enable = 'on';
                app.FitFrameEditField.Value = 1;
                app.FitFrameEditField.Limits = [1 app.SEQparameters.NO_ECHOES];
                app.FitFrameSlider.Value = 1;
                app.FitFrameSlider.Limits = [1 app.SEQparameters.NO_ECHOES];
                app.FitFrameEditField.Enable = 'on';
                app.FitFrameSlider.Enable = 'on';
            else
                app.FrameEditField.Enable = 'off';
                app.FrameSlider.Enable = 'off';
                app.FrameEditField.Value = 1;
                app.FrameEditField.Limits = [1 2];
                app.FrameSlider.Value = 1;
                app.FrameSlider.Limits = [1 2];
                app.FitFrameEditField.Enable = 'off';
                app.FitFrameSlider.Enable = 'off';
                app.FitFrameEditField.Value = 1;
                app.FitFrameEditField.Limits = [1 2];
                app.FitFrameSlider.Value = 1;
                app.FitFrameSlider.Limits = [1 2];
            end
            app.ThresholdEditField.Value = 1;
            app.ThresholdEditField.Limits = [1 32767];
            app.ThresholdSlider.Value = 1;
            app.ThresholdSlider.Limits = [1 32767];
            app.WindowEditField.Value = 32767;
            app.LevelEditField.Value = 16384;
            app.WindowSlider.Value = 32767;
            app.LevelSlider.Value = 16384;
            app.AutoLWButton.Value = 1;
            
            % Determine which flip angle has the highest intensity
            for i = 1:length(app.flipAngles)
                for j = 1:app.SEQparameters.NO_ECHOES
                    avgs(i) = mean(app.image5D(i,j,:));  
                end
            end
            [~,app.maxIndx] = max(avgs);
            app.FlipAngleEditField.Value = app.maxIndx;
            app.FlipAngleSlider.Value = app.maxIndx;
            
            % Initialize fitting mask
            app.mask = ones([size(app.image5D,2),size(app.image5D,3),size(app.image5D,4),size(app.image5D,5)]);
            app.abortFitFlag = false;

            % Segmentation
            app.structuringElement = strel("sphere",1);
            
            % Initialize the T1, M0, and B1 maps
            app.imageT1 = zeros([size(app.image5D,2),size(app.image5D,3),size(app.image5D,4),size(app.image5D,5)]);
            app.imageM0 = zeros([size(app.image5D,2),size(app.image5D,3),size(app.image5D,4),size(app.image5D,5)]);
            app.imageB1 = ones([size(app.image5D,2),size(app.image5D,3),size(app.image5D,4),size(app.image5D,5)]);
            app.t1Cmap = lipariColorMap();
            app.t1Cmap(1,:) = [0 0 0];
            app.lipariButton.Value = 1;
            app.roiFlag = false;
            app.T1roiPoints = [];
            delete(app.T1roi);
            cla(app.RawImage);
            cla(app.T1map);
            cla(app.M0map);
            cla(app.T1FitFig);
            cla(app.DespotT1FitFig);
            
        end % SetParametersFcn


        % ------------------------------------------
        % Plotting figures and images
        % ------------------------------------------
        
        
        function result = CalcAspectratioFnc(app,dim1label,dim2label,sizex,sizey)
        
            if strcmp(dim1label,'X') L1 = app.SEQparameters.FOV3D(1); end
            if strcmp(dim1label,'Y') L1 = app.SEQparameters.FOV3D(2); end    
            if strcmp(dim1label,'Z') L1 = app.SEQparameters.FOV3D(3); end
            if strcmp(dim2label,'X') L2 = app.SEQparameters.FOV3D(1); end
            if strcmp(dim2label,'Y') L2 = app.SEQparameters.FOV3D(2); end    
            if strcmp(dim2label,'Z') L2 = app.SEQparameters.FOV3D(3); end 

            result = (L2/L1)*(sizex/sizey);
            
        end % Aspectratio
        
        function result = CalcAspectratioECVFnc(app,dim1label,dim2label,sizex,sizey)

            if strcmp(dim1label,'X') L1 = app.ECVparameters.FOV3D(1); end
            if strcmp(dim1label,'Y') L1 = app.ECVparameters.FOV3D(2); end    
            if strcmp(dim1label,'Z') L1 = app.ECVparameters.FOV3D(3); end
            if strcmp(dim2label,'X') L2 = app.ECVparameters.FOV3D(1); end
            if strcmp(dim2label,'Y') L2 = app.ECVparameters.FOV3D(2); end    
            if strcmp(dim2label,'Z') L2 = app.ECVparameters.FOV3D(3); end 

            result = (L2/L1)*(sizex/sizey);
            
        end % Aspectratio
         
        function PlotImages(app)
            
            cla(app.RawImage);
            hold(app.RawImage,'on');
            
            if app.validRecoFlag
                
                % Define the colormap
                % Since it is not possible to have 2 colormaps in 1 figure, the
                % colormap is split in a grayscale part [0 1] and a red-color overlay part [1 2]
                cmap1 = [linspace(0,1,128)',linspace(0,1,128)',linspace(0,1,128)'];
                cmap2 = [linspace(0,1,128)', zeros(128,2)];
                cmap = [cmap1;cmap2];
                cmap(cmap > 1) = 1;
                
                % Select the data
                im = app.image5D;
                
                % Select which slice to show
                im = permute(im,app.T1ImageOrient(app.OrientationSpinner.Value,:));
                im = squeeze(im(app.FlipAngleEditField.Value,app.FrameEditField.Value,:,:,app.SliceEditField.Value));

                % Scale
                if app.AutoLWButton.Value == 1
                    maxIm = max(im(:));
                    app.WindowEditField.Value = maxIm;
                    app.WindowSlider.Value = maxIm;
                    app.LevelEditField.Value = maxIm/2;
                    app.LevelSlider.Value = maxIm/2;
                end
                
                % Label the orientations
                app.SliceEditFieldLabel.Text = app.T1ImageOrientLabel(app.OrientationSpinner.Value,3);
                app.XLabel.Text = app.T1ImageOrientLabel(app.OrientationSpinner.Value,1);
                app.YLabel.Text = app.T1ImageOrientLabel(app.OrientationSpinner.Value,2);
                                
                % Image aspect ratio
                if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.SEQparameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                    aratio = CalcAspectratioFnc(app,app.YLabel.Text,app.XLabel.Text,size(im,1),size(im,2));
                else
                    aratio = CalcAspectratioFnc(app,app.XLabel.Text,app.YLabel.Text,size(im,1),size(im,2));
                end
                
                % Background image
                app.RawImage.Position = [21,71,300,300];
                xlim(app.RawImage,[0, size(im,1) + 1]);
                ylim(app.RawImage,[0, size(im,2) + 1]);

                % Window and Level
                im = (1/app.WindowEditField.Value)*(im - app.LevelEditField.Value + app.WindowEditField.Value/2);
                im(im<0) = 0;
                im(im>0.9999) = 0.9999;

                % Background anatomical image
                backgroundimage = imshow(rot90(im),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawImage); %#ok<NASGU> 
                
                % Overlay image
                if app.validMaskFlag
                    overlay = permute(app.mask,app.maskImageOrient(app.OrientationSpinner.Value,:));
                    overlay = squeeze(overlay(app.FrameEditField.Value,:,:,app.SliceEditField.Value));
                    if strcmp(app.XLabel.Text,'X') && strcmp(app.YLabel.Text,'Y') && app.SEQparameters.PHASE_ORIENTATION
                        overlay = rot90(overlay,-1);
                    end
                    overlayimage = imshow(rot90(2-overlay),[0 2],'Colormap',cmap,'InitialMagnification','fit','Parent',app.RawImage);
                    alpha = 0.7-0.7*rot90(overlay);
                    set(overlayimage,'AlphaData',alpha);
                end
                
                % Set aspect ratio
                daspect(app.RawImage,[aratio 1 1]);
                
            end
             
            hold(app.RawImage,'off');
            drawnow;
            
        end % PlotImageFig


        function PlotB1Fig(app)
            
            cla(app.B1map);
            hold(app.B1map,'on');
            
            if app.validRecoFlag
                
                % Select the data
                im = app.imageB1;
                
                % Select which slice to show
                im = permute(im,app.B1ImageOrient(app.B1OrientationSpinner.Value,:));
                im = squeeze(im(:,:,app.B1SliceEditField.Value));
                B1mask = permute(app.mask,app.maskImageOrient(app.B1OrientationSpinner.Value,:));
                B1mask = squeeze(B1mask(1,:,:,app.B1SliceEditField.Value));
                im = im.*B1mask;
            
                % Label the orientations
                app.B1SliceEditFieldLabel.Text = app.T1ImageOrientLabel(app.B1OrientationSpinner.Value,3);
                app.B1XLabel.Text = app.T1ImageOrientLabel(app.B1OrientationSpinner.Value,1);
                app.B1YLabel.Text = app.T1ImageOrientLabel(app.B1OrientationSpinner.Value,2);
                                
                % Image aspect ratio
                if strcmp(app.B1XLabel.Text,'X') && strcmp(app.B1YLabel.Text,'Y') && app.B1Parameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                    aratio = CalcAspectratioFnc(app,app.B1YLabel.Text,app.B1XLabel.Text,size(im,1),size(im,2));
                else
                    aratio = CalcAspectratioFnc(app,app.B1XLabel.Text,app.B1YLabel.Text,size(im,1),size(im,2));
                end
                
                % Plot the image
                app.B1map.Position = [21,71,300,300];
                xlim(app.B1map,[0, size(im,1) + 1]);
                ylim(app.B1map,[0, size(im,2) + 1]);
                imshow(rot90(im),[0.5 1.5],'InitialMagnification','fit','Parent',app.B1map);
                
                % Set aspect ratio
                daspect(app.B1map,[aratio 1 1]);
                
            end
             
            hold(app.B1map,'off');
            drawnow;
            
        end % PlotB1Fig


        function PlotCross(app)

            if app.validFitFlag

                % Image dimensions
                if strcmp(app.T1XLabel.Text,'X') && strcmp(app.T1YLabel.Text,'Y') && app.SEQparameters.PHASE_ORIENTATION
                    px = app.maskImageOrient(app.FitOrientationSpinner.Value,3);
                    py = app.maskImageOrient(app.FitOrientationSpinner.Value,2);
                    pz = app.maskImageOrient(app.FitOrientationSpinner.Value,4);
                else
                    px = app.maskImageOrient(app.FitOrientationSpinner.Value,2);
                    py = app.maskImageOrient(app.FitOrientationSpinner.Value,3);
                    pz = app.maskImageOrient(app.FitOrientationSpinner.Value,4);
                end
                ysize = size(app.imageT1,py);

                % ----- Draw a cross-hair on the T1 map -----

                if app.autumnButton.Value == 1 || app.lipariButton.Value == 1
                    clr = 'g';
                else
                    clr = 'r';
                end

                % Select point in current visible slice and orientation
                app.T1roiPoints = unique(app.T1roiPoints,'rows');
                pnts = app.T1roiPoints(:,[1 px py pz]);
                tColumn = pnts(:,1);
                zColumn = pnts(:,4);
                pnts = pnts(zColumn==app.FitSliceEditField.Value & tColumn==app.FitFrameEditField.Value, :);

                delete(app.T1cross);
                delete(app.M0cross);
                if strcmp(app.T1XLabel.Text,'X') && strcmp(app.T1YLabel.Text,'Y') && app.SEQparameters.PHASE_ORIENTATION
                    app.T1cross = drawcrosshair(app.T1map, 'Position', [pnts(:,3) ysize-pnts(:,2)+1], 'Color', clr, 'LineWidth', 1);
                    app.M0cross = drawcrosshair(app.M0map, 'Position', [pnts(:,3) ysize-pnts(:,2)+1], 'Color', 'r', 'LineWidth', 1);
                else
                    app.T1cross = drawcrosshair(app.T1map, 'Position', [pnts(:,2) ysize-pnts(:,3)+1], 'Color', clr, 'LineWidth', 1);
                    app.M0cross = drawcrosshair(app.M0map, 'Position', [pnts(:,2) ysize-pnts(:,3)+1], 'Color', 'r', 'LineWidth', 1);
                end

            end

        end % PlotCross


        function PlotPoints(app)

            if app.validFitFlag

                % Image dimensions
                if strcmp(app.T1XLabel.Text,'X') && strcmp(app.T1YLabel.Text,'Y') && app.SEQparameters.PHASE_ORIENTATION
                    px = app.maskImageOrient(app.FitOrientationSpinner.Value,3);
                    py = app.maskImageOrient(app.FitOrientationSpinner.Value,2);
                    pz = app.maskImageOrient(app.FitOrientationSpinner.Value,4);
                else
                    px = app.maskImageOrient(app.FitOrientationSpinner.Value,2);
                    py = app.maskImageOrient(app.FitOrientationSpinner.Value,3);
                    pz = app.maskImageOrient(app.FitOrientationSpinner.Value,4);
                end
                ysize = size(app.imageT1,py);

                % ----- Draw points on the T1 map -----

                if app.autumnButton.Value == 1 || app.lipariButton.Value == 1
                    clr = 'g';
                else
                    clr = 'r';
                end

                % Select points in current visible slice and orientation
                app.T1roiPoints = unique(app.T1roiPoints,'rows');
                pnts = app.T1roiPoints(:,[1 px py pz]);
                tColumn = pnts(:,1);
                zColumn = pnts(:,4);
                pnts = pnts(zColumn==app.FitSliceEditField.Value & tColumn==app.FitFrameEditField.Value, :);

                % Plot the points
                hold(app.T1map,'on');
                hold(app.M0map,'on');
                delete(app.T1roi);
                delete(app.M0roi);
                if strcmp(app.T1XLabel.Text,'X') && strcmp(app.T1YLabel.Text,'Y') && app.SEQparameters.PHASE_ORIENTATION
                    app.T1roi = plot(app.T1map, pnts(:,3), ysize-pnts(:,2)+1, 's', 'MarkerSize', 4, 'Color', clr, 'MarkerFaceColor', clr);
                    app.M0roi = plot(app.M0map, pnts(:,3), ysize-pnts(:,2)+1, 's', 'MarkerSize', 4, 'Color', 'r', 'MarkerFaceColor', 'r');
                else
                    app.T1roi = plot(app.T1map, pnts(:,2), ysize-pnts(:,3)+1, 's', 'MarkerSize', 4, 'Color', clr, 'MarkerFaceColor', clr);
                    app.M0roi = plot(app.M0map, pnts(:,2), ysize-pnts(:,3)+1, 's', 'MarkerSize', 4, 'Color', 'r', 'MarkerFaceColor', 'r');
                end
                hold(app.T1map,'off');
                hold(app.M0map,'off');

            end

        end % PlotPoints


        function PlotECVPoints(app)

            if app.validPreGdFlag && ~isempty(app.ECVroiPoints)

                % Image dimensions
                if strcmp(app.PreGdXLabel.Text,'X') && strcmp(app.PreGdYLabel.Text,'Y') && app.ECVparameters.PHASE_ORIENTATION
                    px = app.maskImageOrient(app.ECVOrientationSpinner.Value,3);
                    py = app.maskImageOrient(app.ECVOrientationSpinner.Value,2);
                    pz = app.maskImageOrient(app.ECVOrientationSpinner.Value,4);
                else
                    px = app.maskImageOrient(app.ECVOrientationSpinner.Value,2);
                    py = app.maskImageOrient(app.ECVOrientationSpinner.Value,3);
                    pz = app.maskImageOrient(app.ECVOrientationSpinner.Value,4);
                end
                ysize = size(app.imagePreGd,py);

                % ----- Draw points on the Pre Gd map -----

                if app.ECVautumnButton.Value == 1 || app.ECVlipariButton.Value == 1
                    clr = 'g';
                else
                    clr = 'r';
                end

                % Select points in current visible slice and orientation
                app.ECVroiPoints = unique(app.ECVroiPoints,'rows');
                pnts = app.ECVroiPoints(:,[1 px py pz]);
                tColumn = pnts(:,1);
                zColumn = pnts(:,4);
                pnts = pnts(zColumn==app.ECVSliceEditField.Value & tColumn==app.ECVFrameEditField.Value, :);

                % Plot the points
                hold(app.PreGdmap,'on');
                delete(app.ECVPreRoi);
                if strcmp(app.PreGdXLabel.Text,'X') && strcmp(app.PreGdYLabel.Text,'Y') && app.ECVparameters.PHASE_ORIENTATION
                    app.ECVPreRoi = plot(app.PreGdmap, pnts(:,3), ysize-pnts(:,2)+1, 's', 'MarkerSize', 4, 'Color', clr, 'MarkerFaceColor', clr);
                else
                    app.ECVPreRoi = plot(app.PreGdmap, pnts(:,2), ysize-pnts(:,3)+1, 's', 'MarkerSize', 4, 'Color', clr, 'MarkerFaceColor', clr);
                end
                hold(app.PreGdmap,'off');
              
            end

            if app.validPostGdFlag && ~isempty(app.ECVroiPoints)

                % Image dimensions
                if strcmp(app.PostGdXLabel.Text,'X') && strcmp(app.PostGdYLabel.Text,'Y') && app.ECVparameters.PHASE_ORIENTATION
                    px = app.maskImageOrient(app.ECVOrientationSpinner.Value,3);
                    py = app.maskImageOrient(app.ECVOrientationSpinner.Value,2);
                    pz = app.maskImageOrient(app.ECVOrientationSpinner.Value,4);
                else
                    px = app.maskImageOrient(app.ECVOrientationSpinner.Value,2);
                    py = app.maskImageOrient(app.ECVOrientationSpinner.Value,3);
                    pz = app.maskImageOrient(app.ECVOrientationSpinner.Value,4);
                end
                ysize = size(app.imagePostGd,py);

                % ----- Draw points on the Pre Gd map -----

                if app.ECVautumnButton.Value == 1 || app.ECVlipariButton.Value == 1
                    clr = 'g';
                else
                    clr = 'r';
                end

                % Select points in current visible slice and orientation
                app.ECVroiPoints = unique(app.ECVroiPoints,'rows');
                pnts = app.ECVroiPoints(:,[1 px py pz]);
                tColumn = pnts(:,1);
                zColumn = pnts(:,4);
                pnts = pnts(zColumn==app.ECVSliceEditField.Value & tColumn==app.ECVFrameEditField.Value, :);

                % Plot the points
                hold(app.PostGdmap,'on');
                delete(app.ECVPostRoi);
                if strcmp(app.PostGdXLabel.Text,'X') && strcmp(app.PostGdYLabel.Text,'Y') && app.ECVparameters.PHASE_ORIENTATION
                    app.ECVPostRoi = plot(app.PostGdmap, pnts(:,3), ysize-pnts(:,2)+1, 's', 'MarkerSize', 4, 'Color', clr, 'MarkerFaceColor', clr);
                else
                    app.ECVPostRoi = plot(app.PostGdmap, pnts(:,2), ysize-pnts(:,3)+1, 's', 'MarkerSize', 4, 'Color', clr, 'MarkerFaceColor', clr);
                end
                hold(app.PostGdmap,'off');
              
            end 

        end % PlotECVPoints


        function PlotFits(app,data,t1,stdt1,m0)

            if app.validFitFlag

                % ----- Signal versus flip-angle curve -----

                cla(app.T1FitFig);
                hold(app.T1FitFig,'on');

                fa = app.flipAngles;
                tr = app.SEQparameters.tr;

                % Colormap for datapoints
                cmap = [hex2rgb(app.red) ; hex2rgb(app.blue)];
                alphaColorFlag = cmap(app.FAsIncluded + 1,:);
                alphaColorFlag = alphaColorFlag(1:length(app.flipAngles),:);

                if m0 > 0

                    % fit
                    a = 0 : 0.25: max(fa(:))+5;
                    s = m0*sin(pi*a./180).*(1-exp(-tr/t1))./(1-cos(pi.*a/180)*exp(-tr/t1));
                    plot(app.T1FitFig,a,s,"Color",app.green,"LineWidth",2);

                    % data
                    app.T1FitFig.Position = [27,11,285,204];
                    xlim(app.T1FitFig,[0,max(fa(:))+5]);
                    ylim(app.T1FitFig,[0,1.4*max(data(:))]);
                    scatter(app.T1FitFig,fa,data,[],alphaColorFlag,"filled");

                    % label
                    if stdt1 > 0
                        app.FittedT1Label.Text = ['T1 = ',num2str(t1,'% 10.1f'),' ',char(177),' ',num2str(stdt1,'% 10.1f'),' ms'];
                    else
                        app.FittedT1Label.Text = ['T1 = ',num2str(t1,'% 10.1f'),' ms'];
                    end

                else

                    % Show no data
                    cla(app.T1FitFig);

                    % label
                    app.FittedT1Label.Text = '';

                end

                hold(app.T1FitFig,'off');


                % ----- DESPOT1 representation -----

                cla(app.DespotT1FitFig);
                hold(app.DespotT1FitFig,'on');

                if m0 > 0

                    % fit
                    a = 0 : 0.25: max(fa(:))+10;
                    s = m0*sin(pi*a./180).*(1-exp(-tr/t1))./(1-cos(pi.*a/180)*exp(-tr/t1));
                    da = s./tan(pi*a/180);
                    da = da(~isnan(da));
                    ds = s./sin(pi*a/180);
                    ds = ds(~isnan(ds));
                    plot(app.DespotT1FitFig,da,ds,"Color",app.green,"LineWidth",2);

                    % data
                    x = data./tan(pi*fa/180);
                    y = data./sin(pi*fa/180);
                    app.DespotT1FitFig.Position = [336,23,282,204];
                    scatter(app.DespotT1FitFig,x,y,[],alphaColorFlag,"filled");
                    xlim(app.DespotT1FitFig,[0.9*min(x),1.1*max(x)]);

                else

                    cla(app.DespotT1FitFig);

                end

                hold(app.DespotT1FitFig,'off');

            end
            
        end % PlotFits


    end % Methods


    % --------------------------------------------------
    % Public functions, also available outside the app
    % --------------------------------------------------
    
    methods (Access = public)


        function PlotMaps(app)
            
            cla(app.T1map);
            cla(app.M0map);
            delete(app.T1cbar);
            hold(app.T1map,'on');
            hold(app.M0map,'on');
            
            if app.validFitFlag
                
                % ----- T1 map -----
                
                % Select the data for the T1 map
                im = app.imageT1;
                
                % Select which slice to show
                im = permute(im,app.maskImageOrient(app.FitOrientationSpinner.Value,:));
                im = squeeze(im(app.FitFrameEditField.Value,:,:,app.FitSliceEditField.Value));
                
                % Label the orientations
                app.FitSliceEditFieldLabel.Text = app.T1ImageOrientLabel(app.FitOrientationSpinner.Value,3);
                app.T1XLabel.Text = app.T1ImageOrientLabel(app.FitOrientationSpinner.Value,1);
                app.T1YLabel.Text = app.T1ImageOrientLabel(app.FitOrientationSpinner.Value,2);
                app.M0XLabel.Text = app.T1ImageOrientLabel(app.FitOrientationSpinner.Value,1);
                app.M0YLabel.Text = app.T1ImageOrientLabel(app.FitOrientationSpinner.Value,2);
                
                % Image aspect ratio
                if strcmp(app.T1XLabel.Text,'X') && strcmp(app.T1YLabel.Text,'Y') && app.SEQparameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                    aratio = CalcAspectratioFnc(app,app.T1YLabel.Text,app.T1XLabel.Text,size(im,1),size(im,2));
                else
                    aratio = CalcAspectratioFnc(app,app.T1XLabel.Text,app.T1YLabel.Text,size(im,1),size(im,2));
                end
                
                % Plot the image
                app.T1map.Position = [25,408,300,300];
                xlim(app.T1map,[0, size(im,1) + 1]);
                ylim(app.T1map,[0, size(im,2) + 1]);
                
                % Plot the T1 map
                imshow(rot90(im),[0 app.MaxT1EditField.Value],'Colormap',app.t1Cmap,'InitialMagnification','fit','Parent',app.T1map);
                app.T1cbar = colorbar(app.T1map);
                app.T1cbar.Position = [0.47, 0.83, 0.02, 0.1];
                app.T1cbar.AxisLocation = 'in'; 
                app.T1cbar.Ticks = [0, app.MaxT1EditField.Value];
                app.T1cbar.TickLabels = {"0",num2str(app.MaxT1EditField.Value)};
                app.T1cbar.Color = 'w';
                app.T1cbar.FontSize = 8; 
                
                % Set aspect ratio
                daspect(app.T1map,[aratio 1 1]);

                % ROI display if present
                if app.roiFlag && ~isempty(app.T1roiPoints)
                    app.PlotPoints;
                end
                
                % ----- M0 map -----
                
                % Select the data for the M0 map
                im = app.imageM0;
                
                % Estimate a suitable scale
                scale = 2*median(nonzeros(app.imageM0(:)))+1;
                if isnan(scale) scale = 32767; end
                if scale==0 scale = 32767; end
                
                % Select which slice to show
                im = permute(im,app.maskImageOrient(app.FitOrientationSpinner.Value,:));
                im = squeeze(im(app.FitFrameEditField.Value,:,:,app.FitSliceEditField.Value));
                
                % If phase orientation = 1 rotate image (for viewing purposes only)
                if strcmp(app.T1XLabel.Text,'X') && strcmp(app.T1YLabel.Text,'Y') && app.SEQparameters.PHASE_ORIENTATION
                    im = rot90(im,-1);
                end
                
                % Plot the image
                app.M0map.Position = [336,408,300,300];
                xlim(app.M0map,[0, size(im,1) + 1]);
                ylim(app.M0map,[0, size(im,2) + 1]);
                
                % Plot the T1 map
                imshow(rot90(im),[0 scale],'Colormap',gray,'InitialMagnification','fit','Parent',app.M0map);
                
                % Set aspect ratio
                daspect(app.M0map,[aratio 1 1]);
                
            end
            
            hold(app.T1map,'off');
            hold(app.M0map,'off');
            drawnow;
            
        end % PlotMapsFig


        function PlotECVmaps(app)

            % Try to calculate the ECV maps with current parameters
            [app.imageECV, app.validECVmapFlag] = calculateECV(app);
  
            cla(app.PreGdmap);
            cla(app.PostGdmap);
            cla(app.ECVmap);
            delete(app.ECVcbar1);
            delete(app.ECVcbar2);
            delete(app.ECVcbar3);
            hold(app.PreGdmap,'on');
            hold(app.PostGdmap,'on');
            hold(app.ECVmap,'on');

            % ----- Pre contrast T1 map -----

            if app.validPreGdFlag

                try

                    % Select the data for the T1 map
                    im = app.imagePreGd(:,:,:,:,1);

                    % Select which slice to show
                    im = permute(im,app.maskImageOrient(app.ECVOrientationSpinner.Value,:));
                    im = squeeze(im(app.ECVFrameEditField.Value,:,:,app.ECVSliceEditField.Value));

                    % Label the orientations
                    app.ECVSliceEditFieldLabel.Text = app.T1ImageOrientLabel(app.ECVOrientationSpinner.Value,3);
                    app.PreGdXLabel.Text = app.T1ImageOrientLabel(app.ECVOrientationSpinner.Value,1);
                    app.PreGdYLabel.Text = app.T1ImageOrientLabel(app.ECVOrientationSpinner.Value,2);

                    % Image aspect ratio
                    if strcmp(app.PreGdXLabel.Text,'X') && strcmp(app.PreGdYLabel.Text,'Y') && app.ECVparameters.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        aratio = app.CalcAspectratioECVFnc(app.PreGdYLabel.Text,app.PreGdXLabel.Text,size(im,1),size(im,2));
                    else
                        aratio = app.CalcAspectratioECVFnc(app.PreGdXLabel.Text,app.PreGdYLabel.Text,size(im,1),size(im,2));
                    end

                    % Plot the image
                    app.PreGdmap.Position = [25,408,300,300];
                    xlim(app.PreGdmap,[0, size(im,1) + 1]);
                    ylim(app.PreGdmap,[0, size(im,2) + 1]);

                    % Max value
                    scale1 = round(2*mean(nonzeros(app.imagePreGd(:))));
                    scale1(isnan(scale1)) = 100;
                    scale1(scale1>5000) = 5000;
                    scale2 = round(2*mean(nonzeros(app.imagePostGd(:))));
                    scale2(isnan(scale1)) = 100;
                    scale2(scale1>5000) = 5000;
                    maxVal = max([scale1,scale2]);

                    % Plot the T1 map
                    imshow(rot90(im),[0 maxVal],'Colormap',app.ECVCmap,'InitialMagnification','fit','Parent',app.PreGdmap);
                    app.ECVcbar1 = colorbar(app.PreGdmap);
                    app.ECVcbar1.Position = [0.47, 0.83, 0.02, 0.1];
                    app.ECVcbar1.AxisLocation = 'in';
                    app.ECVcbar1.Ticks = [0, maxVal];
                    app.ECVcbar1.TickLabels = {"0",num2str(maxVal)};
                    app.ECVcbar1.Color = 'w';
                    app.ECVcbar1.FontSize = 8;

                    % Set aspect ratio
                    daspect(app.PreGdmap,[aratio 1 1]);

                    % ROI display if present
                    app.PlotECVPoints;

                catch
                end

            end

            % ----- Post contrast T1 map -----

            if app.validPostGdFlag

                try

                    % Select the data for the T1 map
                    im = app.imagePostGd(:,:,:,:,1);

                    % Select which slice to show
                    im = permute(im,app.maskImageOrient(app.ECVOrientationSpinner.Value,:));
                    im = squeeze(im(app.ECVFrameEditField.Value,:,:,app.ECVSliceEditField.Value));

                    % Label the orientations
                    app.ECVSliceEditFieldLabel.Text = app.T1ImageOrientLabel(app.ECVOrientationSpinner.Value,3);
                    app.PostGdXLabel.Text = app.T1ImageOrientLabel(app.ECVOrientationSpinner.Value,1);
                    app.PostGdYLabel.Text = app.T1ImageOrientLabel(app.ECVOrientationSpinner.Value,2);

                    % Image aspect ratio
                    if strcmp(app.PostGdXLabel.Text,'X') && strcmp(app.PostGdYLabel.Text,'Y') && app.ECVparameters.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        aratio = CalcAspectratioECVFnc(app,app.PostGdYLabel.Text,app.PostGdXLabel.Text,size(im,1),size(im,2));
                    else
                        aratio = CalcAspectratioECVFnc(app,app.PostGdXLabel.Text,app.PostGdYLabel.Text,size(im,1),size(im,2));
                    end

                    % Plot the image
                    app.PostGdmap.Position = [336,408,300,300];
                    xlim(app.PostGdmap,[0, size(im,1) + 1]);
                    ylim(app.PostGdmap,[0, size(im,2) + 1]);

                    % Max value
                    scale1 = round(2*mean(nonzeros(app.imagePreGd(:))));
                    scale1(isnan(scale1)) = 100;
                    scale1(scale1>5000) = 5000;
                    scale2 = round(2*mean(nonzeros(app.imagePostGd(:))));
                    scale2(isnan(scale1)) = 100;
                    scale2(scale1>5000) = 5000;
                    maxVal = max([scale1,scale2]);

                    % Plot the T1 map
                    imshow(rot90(im),[0 maxVal],'Colormap',app.ECVCmap,'InitialMagnification','fit','Parent',app.PostGdmap);
                    app.ECVcbar2 = colorbar(app.PostGdmap);
                    app.ECVcbar2.Position = [0.9575, 0.83, 0.02, 0.1];
                    app.ECVcbar2.AxisLocation = 'in';
                    app.ECVcbar2.Ticks = [0, maxVal];
                    app.ECVcbar2.TickLabels = {"0",num2str(maxVal)};
                    app.ECVcbar2.Color = 'w';
                    app.ECVcbar2.FontSize = 8;

                    % Set aspect ratio
                    daspect(app.PostGdmap,[aratio 1 1]);

                    % ROI display if present
                    app.PlotECVPoints;

                catch
                end

            end

            % ----- ECV map -----

            if app.validECVmapFlag

                try

                    % Select the data for the T1 map
                    im = app.imageECV;

                    % Select which slice to show
                    im = permute(im,app.maskImageOrient(app.ECVOrientationSpinner.Value,:));
                    im = squeeze(im(app.ECVFrameEditField.Value,:,:,app.ECVSliceEditField.Value));

                    % Label the orientations
                    app.ECVSliceEditFieldLabel.Text = app.T1ImageOrientLabel(app.ECVOrientationSpinner.Value,3);
                    app.ECVXLabel.Text = app.T1ImageOrientLabel(app.ECVOrientationSpinner.Value,1);
                    app.ECVYLabel.Text = app.T1ImageOrientLabel(app.ECVOrientationSpinner.Value,2);

                    % Image aspect ratio
                    if strcmp(app.ECVXLabel.Text,'X') && strcmp(app.ECVYLabel.Text,'Y') && app.ECVparameters.PHASE_ORIENTATION
                        im = rot90(im,-1);
                        aratio = CalcAspectratioECVFnc(app,app.ECVYLabel.Text,app.ECVXLabel.Text,size(im,1),size(im,2));
                    else
                        aratio = CalcAspectratioECVFnc(app,app.ECVXLabel.Text,app.ECVYLabel.Text,size(im,1),size(im,2));
                    end

                    % Plot the image
                    app.ECVmap.Position = [336,46,300,300];
                    xlim(app.ECVmap,[0, size(im,1) + 1]);
                    ylim(app.ECVmap,[0, size(im,2) + 1]);

                    % Max value
                    maxVal = 100;

                    % Plot the T1 map
                    imshow(rot90(im),[0 maxVal],'Colormap',app.ECVCmap,'InitialMagnification','fit','Parent',app.ECVmap);
                    app.ECVcbar3 = colorbar(app.ECVmap);
                    app.ECVcbar3.Position = [0.9575, 0.345, 0.02, 0.1];
                    app.ECVcbar3.AxisLocation = 'in';
                    app.ECVcbar3.Ticks = [0, 100];
                    app.ECVcbar3.TickLabels = {"0","100"};
                    app.ECVcbar3.Color = 'w';
                    app.ECVcbar3.FontSize = 8;

                    % Set aspect ratio
                    daspect(app.ECVmap,[aratio 1 1]);

                catch
                end

            end

            hold(app.T1map,'off');
            hold(app.M0map,'off');
            hold(app.ECVmap,'off');
            drawnow;
            
        end % PlotECVimages


        function TextMessage(app, message)
            
            % Text messages in the app
            try
                app.MessageBox.Value = [message ; app.MessageBox.Value];
                app.MessageBox.Value = app.MessageBox.Value(1:50,1);
                drawnow;
            catch
            end
            
        end % TextMessage


        % Status icon
        function SetStatus(app,status)
            
            switch status

                case 0
                    % app.StatusImage.Position = [425,50,30,30];
                    app.StatusImage.ImageSource = 'statusokay.gif';
                    app.StatusLabel.FontColor = app.green;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 14;
                    app.StatusLabel.Text = 'OK';

                case 1
                    % app.StatusImage.Position = [425,50,30,30];
                    app.StatusImage.ImageSource = 'statuswarning.gif';
                    app.StatusLabel.FontColor = app.orange;
                    app.StatusLabel.FontWeight = 'bold';
                    if app.AutoSoundsCheckBox.Value
                        sound(app.warningSound,48000);
                    end
                    app.StatusLabel.FontSize = 11;
                    app.StatusLabel.Text = 'WARNING';

                case 2
                    % app.StatusImage.Position = [425,50,30,30];
                    app.StatusImage.ImageSource = 'statuserror.gif';
                    app.StatusLabel.FontColor = app.brightred;
                    app.StatusLabel.FontWeight = 'bold';
                    if app.AutoSoundsCheckBox.Value
                        sound(app.errorSound,48000);
                    end
                    app.StatusLabel.FontSize = 11;
                    app.StatusLabel.Text = 'ERROR';

                case 3
                    % app.StatusImage.Position = [425,50,30,30];
                    app.StatusImage.ImageSource = 'busy.gif';
                    app.StatusLabel.FontColor = app.blue;
                    app.StatusLabel.FontWeight = 'bold';
                    app.StatusLabel.FontSize = 13;
                    app.StatusLabel.Text = 'BUSY';

            end
            
            drawnow;
            
        end


    end % Methods public
    
    
    % ----------------------------------------------------------------------------


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Disable unnecessary warnings
            warning off;
            clc;

            % Set app theme to light
            theme(app.T1mappUIFigure,"light")
            
            % Main window noname
            app.T1mappUIFigure.Name = '';
            
            % Empty file import and export folders
            app.mrdImportFolder = '';
            app.dcmExportFolder = '';
            app.niftiExportFolder = '';
            app.gifExportFolder = '';
            app.mrdFlag = true;
            app.abortFitFlag = false;
            app.abortRegFlag = false;
            app.validPreGdFlag = false;
            app.validPostGdFlag = false;
            app.ECVparameters.multiFramePre = false;
            app.ECVparameters.multiFramePost = false;
            app.ECVparameters.threeDdataPre = false;
            app.ECVparameters.threeDdataPost = false;

            % Add subfolders to search folder
            if (~isdeployed)
                app.oldFolder = pwd;
                info = dir(which('T1mappD.mlapp'));
                app.appFolder = info.folder;
                cd(app.appFolder);
                addpath(genpath(strcat(pwd,filesep,"functions")));
                addpath(genpath(strcat(pwd,filesep,"gui")));
            end
            
            % Set app layout
            app.SetAppLayoutProperties;
            
            % Colors
            app.ColorsButtonGroupSelectionChanged;
            
            % Add the bart folder to the environment variable if available
            try
                if ismac()
                    [~,result] = system('uname -v');
                    is_m1_mac = any(strfind(result,'ARM64'));
                    if is_m1_mac
                        app.TextMessage('Apple arm64 version');
                    else
                        app.TextMessage('Apple Intel version');
                    end
                else
                    app.TextMessage('PC Intel version');
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % Determine if BART is available by requesting its version number
            app.bartDetectedFlag = false;
            try
                bart(app,'version');
                if ~contains(app.bartVersion,'none')
                    app.bartDetectedFlag = true;
                end
            catch ME
                app.TextMessage(ME.message);
            end

            if app.bartDetectedFlag
                app.TextMessage('BART toolbox detected ...');
            else
                app.TextMessage('WARNING: BART toolbox not available ...');
                app.SetStatus(1);
            end

            % Elastix
            try
                if ismac || isunix
                    [~, result] = system('echo -n $PATH');
                    if ~contains(result,':/usr/local/bin:/usr/local/lib')
                        result = [result ':/usr/local/bin:/usr/local/lib'];
                        setenv('PATH', result);
                    end
                else
                    [elastixDir, ~] = fileparts(which('elastix.exe'));
                    result = getenv('PATH');
                    if ~contains(result,elastixDir)
                        result = [result ';' elastixDir];
                        setenv('PATH', result);
                    end
                end
            catch
            end

            % Trick to automatically include the exe and dll file during compilation
            try
                import('elastix.exe');
                import('transformix.exe');
                import('ANNlib-5.1.dll');
            catch
            end

            % Some other settings
            app.validRecoFlag = false;
            app.validB1Flag = false;
            app.validDataFileFlag = false;
            app.validFitFlag = false;
            app.validRegFlag = false;
            app.validMaskFlag = false;

            % Check version
            app.CheckVersionFnc;

             % Start the parallel pool if it is not running already
            try
                app.TextMessage("Starting the parallel pool ...");
                numCores = feature('numcores');
                p = gcp('nocreate');
                if isempty(p)
                    if numCores >= 8
                        parpool(8);
                    else
                        parpool(numCores);
                    end
                end
            catch ME
                app.TextMessage(ME.message);
            end

            % All okay
            app.SetStatus(0);
            app.SetButtons({'on','off','off','off','off','off','off','on'});
            app.TextMessage("Ready ...");
            
        end

        % Selection changed function: ColorsButtonGroup
        function ColorsButtonGroupSelectionChanged(app, event)

            selectedButton = app.ColorsButtonGroup.SelectedObject;

            switch lower(selectedButton.Text)

                % COLORS:           backgroundColor, fontColor, boxColor, gaugeColor, gaugeBackgroundColor, figureColor, movieFigureColor, gridColor, autoRecoColor, manualRecoColor

                case "classic"

                    guiColors = {app.guiGrey, app.guiBlack, app.guiDarkGrey, app.blue, app.guiWhite, app.guiWhite, app.guiMiddleGrey, app.guiGrey, app.lightblue, app.guiGrey};

                case "black/white"

                    guiColors = {app.guiBlack, app.guiWhite, app.guiDarkGrey, app.blue, app.guiDarkGrey, app.guiGrey, app.guiDarkGrey, app.guiMiddleGrey, app.guiBlackBlue, app.guiBlack};

            end

            app.SetAppColorsFcn(guiColors);

        end

        % Button pushed function: LoadMRDButton
        function LoadMRDButtonPushed(app, event)
            
            % Indicate busy
            app.SetLights({'Yellow','Red','Red','Red','Red','Red','Red'});
            app.SetButtons({'off','off','off','off','off','off','off','off'});
            app.SetStatus(3);

            app.validDataFileFlag = false;
            app.validFitFlag = false;
            app.validRegFlag = false;
  
            % Check standard smis folder first if platform is pc, only 1x per session
            if ispc
                try
                    folderName = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','MrdDirectory');
                    folderName = [folderName,filesep];
                    app.TextMessage('MRD folder found in Windows registry ...');
                    app.TextMessage(strcat("MRD folder = ",folderName," ..."));
                catch ME
                    app.TextMessage(ME.message);
                    app.TextMessage('Trying standard MRD folder ...');
                    folderName = 'c:\smis\dev\MRD\';
                end
                if exist(folderName, 'dir') && app.mrdFlag
                    app.mrdImportFolder = folderName;
                    app.mrdFlag = false;
                end
            end
            
            try

                % Select 1 MRD or multiple b-type files which contains multiple flip-angles data
                
                if contains(app.dataType,'m')
                    g = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                    [mrdFiles{1},app.mrdImportFolder] = uigetfile(strcat(app.mrdImportFolder,filesep,{'*.mrd'}));
                    delete(g);
                    figure(app.T1mappUIFigure);
                else
                    ftypes = {'*.job0', 'B-type files'};
                    mrdFiles = uipickfiles2('FilterSpec',strcat(app.mrdImportFolder,filesep),'Type',ftypes);
                end
                
                % User cancel
                if ~ischar(mrdFiles{1})
                    app.mrdImportFolder = '';
                    ME = MException('T1mappD:userCancel','User cancel ...');
                    throw(ME);
                end

                tmpf = dir(fileparts([mrdFiles{1},filesep]));
                tmpf1 = "";
                for i=1:length(tmpf); tmpf1 = strcat(tmpf1,tmpf(i).name); end

                if contains(mrdFiles{1},"MRD")

                    mrdFile = mrdFiles{1};

                    % MR Solutions data
                    app.TextMessage('Loading MRD k-space data ...');

                    % Import the MRD file
                    importMRDfile(app, mrdFile);
              
                elseif contains(mrdFiles{1},"job0") || contains(tmpf1,"method")

                    % B-type scanner data
                    app.TextMessage('Loading b-type k-space data ...');

                    % Import B-type scanner data
                    err = importBfile(app, mrdFiles);
                    if err
                        ME = MException('T1mappD:invalidfile','Not a valid file ...');
                        throw(ME);
                    end

                else

                    app.mrdImportFolder = '';
                    ME = MException('T1mappD:invalidfile','Not a valid file ...');
                    throw(ME);

                end

                % Success
                app.SetParametersFcn;
                app.OrientationSpinnerValueChanged;
                app.B1OrientationSpinnerValueChanged;

                % Set the buttons for valid file
                app.SetLights({'Green','Red','Red','Red','Red','Red','Red'});
                app.SetButtons({'on','on','on','on','off','off','off','on'});
                app.SetStatus(0);

           catch ME
                
                % Not a valid data file
                app.TextMessage(ME.message);
                app.validRecoFlag = false;
                
                % Set the buttons for invalid data file
                app.SetLights({'Red','Red','Red','Red','Red','Red','Red'});
                app.SetButtons({'on','off','off','off','off','off','off','on'});
                app.SetStatus(2);
                
            end
            
        end

        % Button pushed function: B1LoadMRDButton
        function LoadB1MRDButtonPushed(app, event)
           
            if app.validDataFileFlag

                % Valid B1 data file
                app.validB1Flag = false;

                % Select 1 MRD file which contains multiple flip-angles data for B1 mapping
                try
                    g = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetfile in front
                    [mrdfile,app.mrdImportFolder] = uigetfile(strcat(app.mrdImportFolder,filesep,'*.MRD'));
                    delete(g);
                    figure(app.T1mappUIFigure);
                catch ME
                    app.TextMessage(ME.message);
                end

                % File selected
                try

                    if ischar(mrdfile)

                        % MRD file selected
                        mrdfile = [app.mrdImportFolder,mrdfile];

                        % Load the data
                        app.TextMessage('Reading MRD file ...');
                        [~, fName, ~] = fileparts(mrdfile);

                        if contains(fName,'retro') || contains(fName,'p2roud')
                            centric = false;
                            [data,~,app.B1Parameters] = Get_mrd_3D4(mrdfile,'seq','seq');
                            if isfield(app.B1Parameters,'pe2_centric_on')
                                if app.B1Parameters.pe2_centric_on
                                    [data,~,app.B1Parameters] = Get_mrd_3D4(mrdfile,'seq','cen');
                                    centric = true;
                                end
                            end
                        else
                            centric = true;
                            [data,~,app.B1Parameters] = Get_mrd_3D4(mrdfile,'seq','cen');
                            if isfield(app.B1Parameters,'pe2_centric_on')
                                if ~app.B1Parameters.pe2_centric_on
                                    [data,~,app.B1Parameters] = Get_mrd_3D4(mrdfile,'seq','seq');
                                    centric = false;
                                end
                            end
                        end

                        if centric
                            app.TextMessage('Centric phase encoding ...');
                        else
                            app.TextMessage('Sequential phase encoding ...');
                        end

                        % Valid file check
                        cond1 = false;
                        cond2 = false;

                        if isfield(app.B1Parameters,'VFA_size')
                            if app.B1Parameters.VFA_size > 2
                                cond1 = true;
                            else
                                app.TextMessage('ERROR: Number of flip-angles should be at least 3 ...');
                            end
                        end

                        if isfield(app.B1Parameters,'NO_VIEWS_2')
                            if app.B1Parameters.NO_VIEWS_2 >= 8
                                cond2 = true;
                            else
                                app.TextMessage('ERROR: NO_VIEWS_2 should be at least 8 ...');
                            end
                        end

                        if cond1 && cond2
                         
                            % 3D fft the data
                            app.TextMessage('Fast Fourier Transform ...');
                            faImages = zeros(size(data));
                            for i=1:app.B1Parameters.VFA_size
                                faImages(i,:,:,:) = abs(fft3reco(squeeze(data(i,:,:,:))));
                            end

                            % Data = (flipangles, dimx, dimy, dimz)
                            faImages = flip(permute(faImages,[1,4,2,3]),2);
                            app.B1FlipAngles = app.B1Parameters.VFA_angles(1:app.B1Parameters.VFA_size);

                            % Make the B1 map
                            targetDim = [app.MatrixViewField1.Value,app.MatrixViewField2.Value,app.MatrixViewField3.Value];
                            app.imageB1 = fitB1mapFnc(faImages,app.B1FlipAngles,targetDim);

                            % Plot the B1 map
                            app.B1OrientationSpinnerValueChanged;
                            app.PlotB1Fig;

                            app.TextMessage('B1 map generated ...');

                            % Seems useable B1 map
                            app.validB1Flag = true;

                        end

                    end

                catch ME

                    app.TextMessage(ME.message);
                    app.validB1Flag = false;

                end

            end

        end

        % Value changed function: FAsIncludedViewField
        function FAsIncludedViewFieldValueChanged(app, event)

            chars        = char(app.FAsIncludedViewField.Value);              
            digitMask    = isstrprop(chars, 'digit');
            digitsFound  = double(chars(digitMask) - '0');      % '0'→0, … '9'→9

            N = length(app.flipAngles);
            numFound = numel(digitsFound);
            if numFound < N
                out = [digitsFound, zeros(1, N - numFound)];    % pad with 0s
            else
                out = digitsFound(1:N);                         % truncate excess
            end

            out = reshape(out, 1, []);                          % guarantee row vector
            out = logical(out);                                 % turn into 0s and 1s

            if length(out(out==1))<2
                app.TextMessage("Number of included flip angles should be at least 2 for fitting ...");
                out = ones(1,N);
            end

            app.FAsIncludedViewField.Value = num2str(out);
            app.FAsIncluded = out;
            app.validFitFlag = false;

            % Reset buttons
            app.SetLights({'Null','Null','Green','Red','Red','Red','Red'});
            app.SetButtons({'on','on','on','on','off','off','off','on'});
            app.SetStatus(0);

        end

        % Value changed function: SliceEditField
        function SliceEditFieldValueChanged(app, event)
            
            if app.validRecoFlag
                
                app.SliceSlider.Value = app.SliceEditField.Value;
                app.PlotImages;
                
            end
            
        end

        % Value changed function: SliceSlider
        function SliceSliderValueChanged(app, event)
            
            if app.validRecoFlag
                
                app.SliceEditField.Value = round(app.SliceSlider.Value);
                app.PlotImages;
                
            end
            
        end

        % Value changed function: B1SliceEditField
        function B1SliceEditFieldValueChanged(app, event)

            if app.validRecoFlag && app.validB1Flag

                app.B1SliceSlider.Value = app.B1SliceEditField.Value;
                app.PlotB1Fig;

            end

        end

        % Value changed function: B1SliceSlider
        function B1SliceSliderValueChanged(app, event)
            
            if app.validRecoFlag && app.validB1Flag
                
                app.B1SliceEditField.Value = round(app.B1SliceSlider.Value);
                app.PlotB1Fig;
                
            end
            
        end

        % Value changed function: B1OrientationSpinner
        function B1OrientationSpinnerValueChanged(app, event)
            
            if app.validRecoFlag && app.validB1Flag
                
                % reset the limits to the appropriate bounds
                app.B1SliceEditField.Value = 1;
                app.B1SliceSlider.Value = 1;
                
                app.B1SliceEditField.Limits = [1 size(app.imageB1,app.B1ImageOrient(app.B1OrientationSpinner.Value,3))];
                app.B1SliceSlider.Limits = [1 size(app.imageB1,app.B1ImageOrient(app.B1OrientationSpinner.Value,3))];
                
                app.B1SliceEditField.Value = round(size(app.imageB1,app.B1ImageOrient(app.B1OrientationSpinner.Value,3))/2);
                app.B1SliceSlider.Value = round(size(app.imageB1,app.B1ImageOrient(app.B1OrientationSpinner.Value,3))/2);
               
                % plot the imag
                app.PlotB1Fig;
                
             end
            
        end

        % Value changed function: FlipAngleEditField
        function FlipAngleEditFieldValueChanged(app, event)
             
            if app.validRecoFlag
                
                app.FlipAngleSlider.Value = app.FlipAngleEditField.Value;
                app.PlotImages;
                
            end
            
        end

        % Value changed function: FlipAngleSlider
        function FlipAngleSliderValueChanged(app, event)
            
            if app.validRecoFlag
                
                app.FlipAngleEditField.Value = round(app.FlipAngleSlider.Value);
                app.PlotImages;
                
            end
            
        end

        % Value changed function: FrameEditField
        function FrameEditFieldValueChanged(app, event)
            
            if app.validRecoFlag
                
                app.FrameSlider.Value = app.FrameEditField.Value;
                app.PlotImages;
                
            end
            
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            
            if app.validRecoFlag
                
                app.FrameEditField.Value = round(app.FrameSlider.Value);
                app.PlotImages;
                
            end
            
        end

        % Value changed function: OrientationSpinner
        function OrientationSpinnerValueChanged(app, event)
            
            if app.validRecoFlag
                
                % reset the limits to the appropriate bounds
                app.SliceEditField.Value = 1;
                app.SliceSlider.Value = 1;
                
                app.SliceEditField.Limits = [1 size(app.image5D,app.T1ImageOrient(app.OrientationSpinner.Value,5))];
                app.SliceSlider.Limits = [1 size(app.image5D,app.T1ImageOrient(app.OrientationSpinner.Value,5))];
                
                app.SliceEditField.Value = round(size(app.image5D,app.T1ImageOrient(app.OrientationSpinner.Value,5))/2);
                app.SliceSlider.Value = round(size(app.image5D,app.T1ImageOrient(app.OrientationSpinner.Value,5))/2);
               
                % plot the image
                app.PlotImages;
                
             end
            
        end

        % Button pushed function: RegisterButton
        function RegisterButtonPushed(app, event)
            
            if app.validRecoFlag
                
                app.TextMessage('Automatic image registration ...');
                
                % Indicate busy
                app.SetLights({'Null','Yellow','Red','Red','Red','Red','Red'});
                app.SetButtons({'off','off','off','off','off','off','off','off'});
                app.AbortRegButton.Enable = 'on';
                app.SetStatus(3);
                
                % Initialize progress gauge
                app.RegistrationProgressGauge.Value = 0;
               
                try
                    
                    % Register all slices to 1st echo time image
                    app.abortRegFlag = false;
                    registerImages(app);
                    app.TextMessage('Automatic image registration is ready ...');
                    app.SetLights({'Null','Green','Red','Red','Red','Red','Red'});
                    app.SetButtons({'on','on','on','on','off','off','off','on'});
                    app.AbortRegButton.Enable = 'off';
                    app.SetStatus(0);
                    
                catch ME
                    
                    % Failure
                    app.TextMessage('ERROR: during image registration ...');
                    app.TextMessage(ME.message);
                    app.SetLights({'Null','Red','Red','Red','Red','Red','Red'});
                    app.SetButtons({'on','on','on','on','off','off','off','on'});
                    app.AbortRegButton.Enable = 'off';
                    app.SetStatus(2);
                    
                end
                
            end
            
        end

        % Button pushed function: AbortRegButton
        function AbortRegButtonPushed(app, event)
            
            app.abortRegFlag = true;
            drawnow;

        end

        % Button pushed function: MaskButton
        function MaskButtonPushed(app, event)
          
            if app.validRecoFlag
                
                app.TextMessage('Automatic image segmentation ...');
                
                % Indicate busy
                app.SetLights({'Null','Null','Yellow','Red','Red','Red','Red'});
                app.SetButtons({'off','off','off','off','off','off','off','off'});
                app.SetStatus(3);

                try

                    % Determine automatic threshold using the Otsu method
                    app.threshold = double(graythresh(mat2gray(squeeze(app.image5D(app.maxIndx,:,:,:,:)))) * max(squeeze(app.image5D(app.maxIndx,:))));

                    % Set the automatic mask for all slices
                    app.mask = apply_threshold_3D(app.image5D(app.maxIndx,:,:,:,:),app.threshold);
                    app.mask = reshape(app.mask,size(app.image5D,2),size(app.image5D,3),size(app.image5D,4),size(app.image5D,5));

                    % Display the threshold value for the current slice
                    app.ThresholdEditField.Value = round(app.threshold);
                    app.ThresholdSlider.Value = round(app.threshold);

                    % Mask is valid
                    app.validMaskFlag = true;

                    % Display the mask in the image
                    app.PlotImages;

                    % Reset buttons
                    app.SetLights({'Null','Null','Green','Red','Red','Red','Red'});
                    app.SetButtons({'on','on','on','on','off','off','off','on'});
                    app.SetStatus(0);

                catch ME

                    % Failure
                    app.validMaskFlag = false;
                    app.TextMessage('ERROR: during image segmentation ...');
                    app.TextMessage(ME.message);
                    app.SetLights({'Null','Null','Red','Red','Red','Red','Red'});
                    app.SetButtons({'on','on','on','on','off','off','off','on'});
                    app.SetStatus(2);

                end
                
            end
            
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            
            app.ThresholdSlider.Value = app.ThresholdEditField.Value;

            if app.validRecoFlag

                try

                    % Set the automatic mask for all slices
                    for i = 1:size(app.image5D,2)
                        app.mask(i,:,:,:) = apply_threshold_3D(squeeze(app.image5D(app.maxIndx,i,:,:,:)),app.ThresholdEditField.Value);
                    end
                    
                    % Mask is valid
                    app.validMaskFlag = true;

                    % Plot the images with the new mask
                    app.PlotImages;

                    % Reset buttons
                    app.SetLights({'Null','Null','Green','Red','Red','Red','Red'});
                    app.SetButtons({'on','on','on','on','off','off','off','on'});
                    app.SetStatus(0);

                catch ME

                    %Failure
                    app.validMaskFlag = false;
                    app.TextMessage('ERROR: during image segmentation ...');
                    app.TextMessage(ME.message);
                    app.SetLights({'Null','Null','Red','Red','Red','Red','Red'});
                    app.SetButtons({'on','on','on','on','off','off','off','on'});
                    app.SetStatus(2);

                end

            end
            
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            
            app.ThresholdEditField.Value = round(app.ThresholdSlider.Value);
            app.ThresholdEditFieldValueChanged;
            
        end

        % Button pushed function: ErodeButton
        function ErodeButtonPushed(app, event)
            
            if app.validRecoFlag
                
                try

                    % Morphological erosion
                    for frame = 1:size(app.mask,1)
                        app.mask(frame,:,:,:) = 1-imerode(1-app.mask(frame,:,:,:),app.structuringElement);
                    end

                    % Display the result;
                    app.PlotImages;

                catch ME
                    
                    app.TextMessage(ME.message);
                    
                end
                
                % Reset buttons
                app.SetLights({'Null','Null','Green','Red','Red','Red','Red'});
                app.SetButtons({'on','on','on','on','off','off','off','on'});
                app.SetStatus(0);
                
            end
        
        end

        % Button pushed function: DilateButton
        function DilateButtonPushed(app, event)
            
            if app.validRecoFlag
                
                try

                    % Morphological dilation
                    for frame = 1:size(app.mask,1)
                        app.mask(frame,:,:,:) = 1-imdilate(1-app.mask(frame,:,:,:),app.structuringElement);
                    end

                    % Display the result;
                    app.PlotImages;

                catch ME

                    app.TextMessage(ME.message);

                end
                
                % Reset buttons
                app.SetLights({'Null','Null','Green','Red','Red','Red','Red'});
                app.SetButtons({'on','on','on','on','off','off','off','on'});
                app.SetStatus(0);
                
            end
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            
            if app.validRecoFlag
                
                try

                    % Morphological open
                    for frame = 1:size(app.mask,1)
                        app.mask(frame,:,:,:) = 1-imopen(1-app.mask(frame,:,:,:),app.structuringElement);
                    end

                    % Display the result;
                    app.PlotImages;

                catch ME

                    app.TextMessage(ME.message);

                end
                
                % Reset buttons
                app.SetLights({'Null','Null','Green','Red','Red','Red','Red'});
                app.SetButtons({'on','on','on','on','off','off','off','on'});
                app.SetStatus(0);
                
            end
        
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
        
            if app.validRecoFlag
                
                try

                    % Morphological close, dilation, followed by erosion
                    for frame = 1:size(app.mask,1)
                        app.mask(frame,:,:,:) = 1-imdilate(1-app.mask(frame,:,:,:),app.structuringElement);
                        app.mask(frame,:,:,:) = 1-imerode(1-app.mask(frame,:,:,:),app.structuringElement);
                    end

                    % Display the result;
                    app.PlotImages;

                catch ME

                    app.TextMessage(ME.message);

                end
                
                % Reset buttons
                app.SetLights({'Null','Null','Green','Red','Red','Red','Red'});
                app.SetButtons({'on','on','on','on','off','off','off','on'});
                app.SetStatus(0);
                
            end    
        
        end

        % Value changed function: WindowEditField
        function WindowEditFieldValueChanged(app, event)
            
            app.WindowSlider.Value = app.WindowEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImages;
            
        end

        % Value changed function: LevelEditField
        function LevelEditFieldValueChanged(app, event)
            
            app.LevelSlider.Value = app.LevelEditField.Value;
            app.AutoLWButton.Value = 0;
            app.PlotImages;
            
        end

        % Value changed function: WindowSlider
        function WindowSliderValueChanged(app, event)
            
            app.WindowEditField.Value = round(app.WindowSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImages;
            
        end

        % Value changed function: LevelSlider
        function LevelSliderValueChanged(app, event)
            
            app.LevelEditField.Value = round(app.LevelSlider.Value);
            app.AutoLWButton.Value = 0;
            app.PlotImages;
            
        end

        % Value changed function: AutoLWButton
        function AutoLWButtonValueChanged(app, event)
            
            if app.AutoLWButton.Value == 1
                app.PlotImages;
            end
            
        end

        % Button pushed function: FitT1Button
        function FitT1ButtonPushed(app, event)
          
            if app.validRecoFlag
                
                % Disable segmentation buttons to prevent change during fit
                app.DisableSegmentButtons;
                app.SetLights({'Null','Null','Null','Yellow','Red','Red','Red'});
                app.SetButtons({'off','off','off','off','off','off','off','off'});
                app.AbortFitButton.Enable = 'on';
                app.abortFitFlag = false;
                app.SetStatus(3);

                try

                    % Perform the fit
                    fitT1(app);

                    % Update
                    app.FitOrientationSpinnerValueChanged;
                    app.AutoButtonPushed;
                    app.EnableSegmentButtons;

                    % Reset buttons
                    app.SetLights({'Null','Null','Null','Green','Red','Red','Red'});
                    app.SetButtons({'on','on','on','on','on','on','on','on'});
                    app.AbortFitButton.Enable = 'off';
                    app.SetStatus(0);

                catch ME

                    % Failure;
                    app.TextMessage('ERROR: during fitting ...');
                    app.TextMessage(ME.message);

                    app.SetLights({'Null','Null','Null','Green','Red','Red','Red'});
                    app.SetButtons({'on','on','on','on','on','on','on','on'});
                    app.AbortFitButton.Enable = 'off';
                    app.SetStatus(2);
                    app.EnableSegmentButtons;

                end

            end
            
        end

        % Button pushed function: AbortFitButton
        function AbortFitButtonPushed(app, event)
            
            app.abortFitFlag = true;
            drawnow;
        
        end

        % Selection changed function: ColormapButtonGroup
        function ColormapButtonGroupSelectionChanged(app, event)
            
            if app.grayButton.Value == 1
                app.t1Cmap = gray;
            end
            
            if app.autumnButton.Value == 1
                app.t1Cmap = autumn;
            end
            
            if app.winterButton.Value == 1
                app.t1Cmap = winter;
            end
            
            if app.parulaButton.Value == 1
                app.t1Cmap = parula;
            end
            
            if app.lipariButton.Value == 1
                app.t1Cmap = lipariColorMap();
            end
            
            % Background should always be black
            app.t1Cmap(1,:) = [0 0 0];
            
            % Plot the maps with the new color map
            app.PlotMaps;
            
        end

        % Button pushed function: AutoButton
        function AutoButtonPushed(app, event)
            
            if app.validFitFlag
                
                % Estimate a suitable scale for the T1 map
                % Auto-scale = 2 x mean of the T1 values in the image
                scale = round(2*mean(nonzeros(app.imageT1(:))));
                
                if isnan(scale) scale = 1000; end
                if scale==0 scale = 1000; end
                if scale>5000 scale = 5000; end
                app.MaxT1EditField.Value = scale;
                
                % plot the maps
                app.PlotMaps;
                
            end
            
        end

        % Window scroll wheel function: T1mappUIFigure
        function T1mappUIFigureWindowScrollWheel(app, event)

            verticalScrollCount = event.VerticalScrollCount;

            % Get the Position of the GUI and the position of the mouse
            set(0,'units','pixels');
            guiposition = get(app.T1mappUIFigure,'Position');
            cursorposition = get(0,'PointerLocation');
            Xpos = cursorposition(1)-guiposition(1);

            if Xpos < 705

                if app.validRecoFlag

                    if verticalScrollCount > 0
                        newslice = app.SliceEditField.Value + 1;
                        newslice(newslice>app.SliceEditField.Limits(2)) = app.SliceEditField.Limits(2);
                    else
                        newslice = app.SliceEditField.Value - 1;
                        newslice(newslice<1) = 1;
                    end

                    app.SliceEditField.Value = newslice;
                    app.SliceEditFieldValueChanged;

                end

            else

                if ~app.roiFlag

                    if app.validFitFlag

                        if verticalScrollCount > 0
                            newslice = app.FitSliceEditField.Value + 1;
                            newslice(newslice>app.FitSliceEditField.Limits(2)) = app.FitSliceEditField.Limits(2);
                        else
                            newslice = app.FitSliceEditField.Value - 1;
                            newslice(newslice<1) = 1;
                        end

                        app.FitSliceEditField.Value = newslice;
                        app.FitSliceEditFieldValueChanged;

                    end

                end

            end

            drawnow;

        end

        % Value changed function: MaxT1EditField
        function MaxT1EditFieldValueChanged(app, event)
            
            % plot the maps
            app.PlotMaps;
            
        end

        % Value changed function: FitSliceEditField
        function FitSliceEditFieldValueChanged(app, event)
             
            if app.validFitFlag
                
                app.FitSliceSlider.Value = app.FitSliceEditField.Value;
                app.PlotMaps;
                
            end
            
        end

        % Value changed function: FitSliceSlider
        function FitSliceSliderValueChanged(app, event)
            
            if app.validFitFlag
                
                app.FitSliceEditField.Value = round(app.FitSliceSlider.Value);
                app.PlotMaps;
                
            end
             
        end

        % Value changed function: FitFrameEditField
        function FitFrameEditFieldValueChanged(app, event)
             
            if app.validFitFlag
                
                app.FitFrameSlider.Value = app.FitFrameEditField.Value;
                app.PlotMaps;
                
            end
            
        end

        % Value changed function: FitFrameSlider
        function FitFrameSliderValueChanged(app, event)
            
            if app.validFitFlag
                
                app.FitFrameEditField.Value = round(app.FitFrameSlider.Value);
                app.PlotMaps;
                
            end
            
        end

        % Value changed function: FitOrientationSpinner
        function FitOrientationSpinnerValueChanged(app, event)
            
            if app.validFitFlag
                
                % reset the limits to the appropriate bounds
                app.FitSliceEditField.Value = 1;
                app.FitSliceSlider.Value = 1;
                
                app.FitSliceEditField.Limits = [1 size(app.imageT1,app.maskImageOrient(app.FitOrientationSpinner.Value,4))];
                app.FitSliceSlider.Limits = [1 size(app.imageT1,app.maskImageOrient(app.FitOrientationSpinner.Value,4))];
                
                app.FitSliceEditField.Value = round(size(app.imageT1,app.maskImageOrient(app.FitOrientationSpinner.Value,4))/2);
                app.FitSliceSlider.Value = round(size(app.imageT1,app.maskImageOrient(app.FitOrientationSpinner.Value,4))/2);
               
                % plot the image
                app.PlotMaps;
                
             end
            
        end

        % Image clicked function: M0image, T1image
        function T1M0mapImageClicked(app, event)
        
            if app.validFitFlag

                try

                    % Get the Position of the GUI and the position of the mouse click
                    set(0,'units','pixels');
                    guiposition = get(app.T1mappUIFigure,'Position');
                    cursorposition = get(0,'PointerLocation');

                    % Position of the cursor click in the image window
                    imagepos = [743,449]; % position of bottom-left corner of image in the app ~ (745,449)
                    Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                    Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

                    Xpos(Xpos>311) = Xpos-311;

                    % Image orientation and aspect ratio
                    if strcmp(app.T1XLabel.Text,'X') && strcmp(app.T1YLabel.Text,'Y') && app.SEQparameters.PHASE_ORIENTATION
                        px = app.maskImageOrient(app.FitOrientationSpinner.Value,3);
                        py = app.maskImageOrient(app.FitOrientationSpinner.Value,2);
                        pz = app.maskImageOrient(app.FitOrientationSpinner.Value,4);
                    else
                        px = app.maskImageOrient(app.FitOrientationSpinner.Value,2);
                        py = app.maskImageOrient(app.FitOrientationSpinner.Value,3);
                        pz = app.maskImageOrient(app.FitOrientationSpinner.Value,4);
                    end
              
                    aratio = app.SEQparameters.FOV3D(px-1)/app.SEQparameters.FOV3D(py-1);
                    xsize = size(app.imageT1,px);
                    ysize = size(app.imageT1,py);

                    % Correct for non-square image
                    imsize = 289;
                    if aratio >= 1
                        xcor = (Xpos/imsize) * xsize;
                        ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                    else
                        xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                        ycor = (Ypos/imsize) * ysize;
                    end

                    % Round to nearest integer
                    xcor = round(xcor);
                    ycor = round(ycor);

                    % Keep the values within matrix boundaries
                    xcor(xcor<1) = 1;
                    ycor(ycor<1) = 1;
                    xcor(xcor>xsize) = xsize;
                    ycor(ycor>ysize) = ysize;
                 
                    % Current slice and time frame
                    zcor = app.FitSliceEditField.Value;
                    timeFrame = app.FitFrameEditField.Value;

                    % Inverse orientation
                    pi = [1 px py pz];
                    pi = pi(:,pi);

                    if app.roiFlag

                        % Include points within Radius
                        se = strel("disk",app.BrushEditField.Value-1);    
                        [nzrow,nzcol] = find(se.Neighborhood==1); 
                        nzc = [nzrow,nzcol] - [app.BrushEditField.Value,app.BrushEditField.Value];

                        % Paint ROI
                        for i = 1:size(nzc,1)
                            if (xcor+nzc(i,1)>0) && (ycor+nzc(i,2)>0) && (xcor+nzc(i,1)<=xsize) && (ycor+nzc(i,2)<=ysize)
                                addPoint = [timeFrame xcor+nzc(i,1) ycor+nzc(i,2) zcor];
                                addPoint = addPoint(:,pi);
                                app.T1roiPoints = [app.T1roiPoints; addPoint];
                            end
                        end
                        app.T1roiPoints = unique(app.T1roiPoints,'rows');
                        app.PlotPoints;

                    else

                        % Plot a cross
                        addPoint = [timeFrame xcor ycor zcor];
                        app.T1roiPoints = addPoint(:,pi);
                        app.PlotCross;

                    end

                    % Select the raw image data in the selected point / ROI
                    msk = app.mask;
                    msk = reshape(msk,[1 size(msk)]);
                    imdata = app.image5D.*msk;
                    for i = 1:size(app.T1roiPoints,1)
                        if strcmp(app.T1XLabel.Text,'X') && strcmp(app.T1YLabel.Text,'Y') && app.SEQparameters.PHASE_ORIENTATION
                            rawdatas(i,:) =  imdata(:,app.T1roiPoints(i,1),ysize-app.T1roiPoints(i,3)+1,app.T1roiPoints(i,2),app.T1roiPoints(i,4)); %#ok<*AGROW> 
                        else
                            rawdatas(i,:) =  imdata(:,app.T1roiPoints(i,1),app.T1roiPoints(i,2),app.T1roiPoints(i,3),app.T1roiPoints(i,4)); %#ok<*AGROW> 
                        end
                    end

                    % Mean of the data points in the ROI
                    if size(rawdatas,1)>1
                        rawdatas(rawdatas==0) = nan;
                        rawdata = mean(rawdatas,'omitnan');             % trick to exclude ROI pixels in background
                    else
                        rawdata = rawdatas;
                    end

                    % Determine mean T1 and M0 in point / ROI
                    for i = 1:size(app.T1roiPoints,1)
                        if strcmp(app.T1XLabel.Text,'X') && strcmp(app.T1YLabel.Text,'Y') && app.SEQparameters.PHASE_ORIENTATION
                            t1values(i) = app.imageT1(app.T1roiPoints(i,1),ysize-app.T1roiPoints(i,3)+1,app.T1roiPoints(i,2),app.T1roiPoints(i,4));
                            m0values(i) = app.imageM0(app.T1roiPoints(i,1),ysize-app.T1roiPoints(i,3)+1,app.T1roiPoints(i,2),app.T1roiPoints(i,4));
                        else
                            t1values(i) = app.imageT1(app.T1roiPoints(i,1),app.T1roiPoints(i,2),app.T1roiPoints(i,3),app.T1roiPoints(i,4));
                            m0values(i) = app.imageM0(app.T1roiPoints(i,1),app.T1roiPoints(i,2),app.T1roiPoints(i,3),app.T1roiPoints(i,4));
                        end
                    end

                    r1values = 1./double(t1values);
                    r1values = nonzeros(r1values);
                    r1values(isnan(r1values)) = [];
                    r1values(isinf(r1values)) = [];
                    t1value = 1/mean(r1values);                         % T1 = 1 / mean(R1 values in pixels)
                    stdt1value = t1value*std(r1values)/mean(r1values);  % error propagation with division

                    m0value = mean(nonzeros(double(m0values)));
                    m0value(isnan(m0value)) = 0;

                    % Plot the raw data and fitted curves
                    app.PlotFits(rawdata,t1value,stdt1value,m0value);

                catch ME

                    app.TextMessage('ERROR: Something went wrong during ROI selection ...');
                    app.TextMessage(ME.message);

                end

            end
            
        end

        % Button pushed function: ROIbutton
        function ROIButtonPushed(app, event)
            
             % Toggle between ROI drawing and cross-hair drawing
             
             if strcmp(app.ROIbutton.Text,'ROI')
                 
                 app.ROIbutton.Text = 'Cross';
                 app.TextMessage('ROI drawing selected ...');
                 app.EraseButton.Enable = 'on';
                 app.BrushEditField.Enable = 'on';
                 app.BrushEditFieldLabel.Enable = 'on';
                 app.roiFlag = true;
             
             else  
                 
                 app.ROIbutton.Text = 'ROI';
                 app.TextMessage('Cross-hair selected ...');
                 app.EraseButton.Enable = 'off';
                 app.BrushEditField.Enable = 'off';
                 app.BrushEditFieldLabel.Enable = 'off';
                 app.roiFlag = false;
                 
             end
            
             app.T1roiPoints = [];
             delete(app.T1roi);
             delete(app.T1cross);
             app.PlotMaps;
            
        end

        % Button pushed function: EraseButton
        function ROIEraseButtonPushed(app, event)

            app.T1roiPoints = [];
            delete(app.T1roi);
            app.PlotMaps;

        end

        % Button pushed function: DCMNIFTIButton
        function ExportDCMNIFTIButtonPushed(app, event)
            
            if app.validFitFlag

                % Indicate busy
                app.SetLights({'Null','Null','Null','Null','Yellow','Null','Null'});
                app.SetButtons({'off','off','off','off','off','off','off','off'});
                app.SetStatus(3);
             
                try

                    % Search for dicom file
                    app.TextMessage('DICOM export ...');

                    % Assuming that MRD and Data folder share the same base-folder
                    % Example: base / MRD / 343 / 8455 /

                    % Base / data / 343 / DICOM / 8455 /
                    dcmSearchFolder{1} = replace(app.mrdImportFolder,"MRD","Data");
                    try
                        k = strfind(dcmSearchFolder{1},"Data");
                        fs = strfind(dcmSearchFolder{1}(k:end),filesep);
                        dcmSearchFolder{1}= dcmSearchFolder{1}(1:k+fs(3)-1);
                        dcmSearchFolder{1} = insertAfter(dcmSearchFolder{1},k+fs(2)-1,strcat("DICOM",filesep));
                        dcmIndx{1} = 2;
                    catch
                        dcmSearchFolder{1} = app.mrdImportFolder;
                    end

                    % Base / data / 343 / DICOM / 8455 / 1 /
                    dcmSearchFolder{2} = strcat(dcmSearchFolder{1},'1',filesep);
                    dcmIndx{2} = 3;

                    % Base / data / 343 / DICOM / 8455 / 2 /
                    dcmSearchFolder{3} = strcat(dcmSearchFolder{1},'2',filesep);
                    dcmIndx{3} = 3;

                    % Base / data / 343 / DICOM / 8455 / 3 /
                    dcmSearchFolder{4} = strcat(dcmSearchFolder{1},'3',filesep);
                    dcmIndx{4} = 3;

                    % Check if one of the folders contains dicom files
                    dcmExist = false;
                    for i=1:length(dcmSearchFolder)
                        if ~isempty(dir(strcat(dcmSearchFolder{i},"*.dcm")))
                            dcmFolder = dcmSearchFolder{i};
                            app.TextMessage(strcat("Found DICOM information in ",dcmFolder));
                            filesepIdx = strfind(dcmFolder, filesep);
                            app.dcmExportFolder = dcmFolder(1:filesepIdx(end-dcmIndx{i}));
                            dcmExist = true;
                            break;
                        end
                    end

                    if dcmExist

                        try
                            % Check if folder is actually writable
                            mkdir(strcat(app.dcmExportFolder,filesep,"testFolder"));
                            rmdir(strcat(app.dcmExportFolder,filesep,"testFolder"));
                        catch
                            app.T1mappUIFigure.Visible = 'off';
                            app.dcmExportFolder = uigetdir(app.dcmExportFolder,"DICOM export folder ...");
                            app.T1mappUIFigure.Visible = 'on';
                            drawnow;
                            if ~ischar(app.dcmExportFolder)
                                ME = MException('T1mappD:userCancel','User cancel ...');
                                throw(ME);
                            end
                        end

                        % Dicom export using original dicom tags
                        app.exportFolder = export_dicom_3DT1_dcm(app,dcmFolder);

                    else

                        % Ask for a dicom export folder
                        app.T1mappUIFigure.Visible = 'off';
                        app.dcmExportFolder = uigetdir(app.dcmExportFolder,"DICOM export folder ...");
                        app.T1mappUIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.dcmExportFolder)
                            ME = MException('T1mappD:userCancel','User cancel ...');
                            throw(ME);
                        end
                     
                        % Dicom export without dicom tag information
                        app.exportFolder = export_dicom_3DT1(app, strcat(app.dcmExportFolder,filesep,"DICOM",filesep));

                    end

                    % NIFTI export
                    try
                        export_nifti_3DT1(app);
                    catch ME
                        app.TextMessage(ME.message);
                        app.TextMessage("ERROR: NIfti export failed ...");
                    end

                    % Success
                    app.TextMessage('DICOM export finished ...');
                    app.SetStatus(0);

                    % Reset buttons
                    app.SetLights({'Null','Null','Null','Null','Green','Null','Null'});
                    app.SetButtons({'on','on','on','on','on','on','on','on'});

               catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.dcmExportFolder = '';

                    % Reset buttons
                    app.SetLights({'Null','Null','Null','Null','Red','Null','Null'});
                    app.SetButtons({'on','on','on','on','on','on','on','on'});
                    app.SetStatus(2);

               end

            end
            
        end

        % Button pushed function: ExportNIFTIButton
        function ExportNIFTIButtonPushed(app, event)

            if app.validFitFlag

                % Indicate busy
                app.SetLights({'Null','Null','Null','Null','Null','Yellow','Null'});
                app.SetButtons({'off','off','off','off','off','off','off','off'});
                app.SetStatus(3);

                try

                    % Make base / data / study / Nifti
                    try
                        tmpNiftiExportFolder = replace(app.mrdImportFolder, "MRD", "Data");
                        k = strfind(tmpNiftiExportFolder,"Data");
                        fs = strfind(tmpNiftiExportFolder(k:end),filesep);
                        tmpNiftiExportFolder = tmpNiftiExportFolder(1:k+fs(2)-1);
                        tmpNiftiExportFolder = strcat(tmpNiftiExportFolder,filesep,"Nifti",filesep);
                        app.niftiExportFolder = tmpNiftiExportFolder;
                        % Check if folder is actually writable
                        mkdir(strcat(app.niftiExportFolder,filesep,"testFolder"));
                        rmdir(strcat(app.niftiExportFolder,filesep,"testFolder"));
                        niftiFolderExists = true;
                    catch
                        niftiFolderExists = false;
                    end

                    % Ask for folder if Nifti folder does not exists
                    if ~niftiFolderExists
                        tmpNiftiExportFolder = app.niftiExportFolder;
                        app.T1mappUIFigure.Visible = 'off';
                        app.niftiExportFolder = uigetdir(app.niftiExportFolder,'NIFTI export folder ...');
                        app.T1mappUIFigure.Visible = 'on';
                        drawnow;
                        if ~ischar(app.niftiExportFolder)
                            app.niftiExportFolder = tmpNiftiExportFolder;
                            ME = MException('T1map:userCancel','User cancel ...');
                            throw(ME);
                        end
                        finalNiftiExportFolder = strcat(app.niftiExportFolder,filesep,"Nifti",filesep);
                    end

                    app.TextMessage('Exporting to NIFTI ...');
                    app.TextMessage(strcat("Nifti export folder = ",finalNiftiExportFolder," ..."));

                    % Create new folder
                    ready = false;
                    cnt = 1;
                    while ~ready
                        nifitExportFolder = strcat(finalNiftiExportFolder,app.SEQparameters.tag,'T1',filesep,num2str(cnt),filesep);
                        if ~exist(nifitExportFolder, 'dir')
                            mkdir(nifitExportFolder);
                            ready = true;
                        end
                        cnt = cnt + 1;
                    end

                    % Image dimensions
                    imT1 = cast(app.imageT1,'single');
                    imM0 = cast(app.imageM0,'single');
                    imT1 = permute(imT1,[2 3 4 1]);
                    imM0 = permute(imM0,[2 3 4 1]);

                    % Phase orientation
                    if isfield(app.SEQparameters, 'PHASE_ORIENTATION')
                        if app.SEQparameters.PHASE_ORIENTATION == 1
                            app.TextMessage('INFO: phase orientation = 1');
                            imT1 = permute(rot90(permute(imT1,[2 1 3 4]),1),[2 1 3 4]);
                            imM0 = permute(rot90(permute(imM0,[2 1 3 4]),1),[2 1 3 4]);
                            FOVx = app.SEQparameters.FOV3D(2);
                            FOVy = app.SEQparameters.FOV3D(1);
                            FOVz = app.SEQparameters.FOV3D(3);
                        else
                            FOVx = app.SEQparameters.FOV3D(1);
                            FOVy = app.SEQparameters.FOV3D(2);
                            FOVz = app.SEQparameters.FOV3D(3);
                        end
                    end
                    imT1 = flip(imT1,1);
                    imM0 = flip(imM0,1);
                    [dimx,dimy,dimz,nrframes] = size(imT1);

                    % Set some parameters
                    load('niftiinfo.mat'); %#ok<LOAD>

                    info.Filemoddate = datestr(datetime); %#ok<DATST> 
                    info.Filesize = nrframes*dimx*dimy*dimz*32;
                    info.Datatype = 'single';
                    info.BitsPerPixel = 32;
                    info.Version = 1;

                    % Frames yes or no
                    app.SEQparameters.timeperframe = 0.100/nrframes;
                    if nrframes>1
                        info.ImageSize = [dimx dimy dimz nrframes];
                        info.PixelDimensions = [FOVx/dimx FOVy/dimy FOVz/dimz app.SEQparameters.timeperframe];
                    else
                        info.ImageSize = [dimx dimy dimz];
                        info.PixelDimensions = [FOVx/dimx FOVy/dimy FOVz/dimz];
                    end

                    % Dimensions
                    info.raw.dim = [3 dimx dimy dimz nrframes 1 1 1 1];
                    info.raw.pixdim = [FOVx/dimx FOVy/dimy FOVz/dimz app.SEQparameters.timeperframe 0 0 0 0];
                    info.vox_offset = 0;

                    info.Filename = 'im3D_T1.nii';
                    info.Description = 'T1-map';
                    niftiwrite(imT1,strcat(nifitExportFolder,filesep,'im3D_nii1_T1.nii'),info,"Version","NIfTI1");
                    niftiwrite(imT1,strcat(nifitExportFolder,filesep,'im3D_nii2_T1.nii'),info,"Version","NIfTI2");
                    info.Filename = 'im3D_M0.nii';
                    info.Description = 'M0-map';
                    niftiwrite(imM0,strcat(nifitExportFolder,filesep,'im3D_nii1_M0.nii'),info,"Version","NIfTI1");
                    niftiwrite(imM0,strcat(nifitExportFolder,filesep,'im3D_nii2_M0.nii'),info,"Version","NIfTI2");

                    % Success
                    app.TextMessage('NIFTI export finished ...');
                    app.SetStatus(0);

                    % Reset buttons
                    app.SetLights({'Null','Null','Null','Null','Null','Green','Null'});
                    app.SetButtons({'on','on','on','on','on','on','on','on'});

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.niftiExportFolder = '';

                    % Reset buttons
                    app.SetLights({'Null','Null','Null','Null','Null','Red','Null'});
                    app.SetButtons({'on','on','on','on','on','on','on','on'});
                    app.SetStatus(2);

                end

            end
           
        end

        % Button pushed function: GIFButton
        function ExportGIFButtonPushed(app, event)
            
            if app.validFitFlag

                % Indicate busy
                app.SetLights({'Null','Null','Null','Null','Null','Null','Yellow'});
                app.SetButtons({'off','off','off','off','off','off','off','off'});
                app.SetStatus(3);

                try

                    try
                        tmpGifExportFolder = app.gifExportFolder;
                        app.gifExportFolder = replace(app.mrdImportFolder, "MRD", "Data");
                        k = strfind(app.gifExportFolder,"Data");
                        fs = strfind(app.gifExportFolder(k:end),filesep);
                        app.gifExportFolder = app.gifExportFolder(1:k+fs(2)-1);
                        % Check if folder is actually writable
                        mkdir(strcat(app.gifExportFolder,filesep,"testFolder"));
                        rmdir(strcat(app.gifExportFolder,filesep,"testFolder"));
                        gifFolderExist = true;
                    catch
                        gifFolderExist = false;
                    end

                    if ~gifFolderExist
                        app.T1mappUIFigure.Visible = 'off';
                        app.gifExportFolder = uigetdir(tmpGifExportFolder,'GIF export folder ...');
                        app.T1mappUIFigure.Visible = 'on';
                        if ~ischar(app.gifExportFolder)
                            app.gifExportFolder = tmpGifExportFolder;
                            ME = MException('T1map:userCancel','User cancel ...');
                            throw(ME);
                        end
                    end

                    % T1 map in viewing orientation
                    imT1 = app.imageT1;
                    imT1 = permute(imT1,app.maskImageOrient(app.FitOrientationSpinner.Value,:));

                    % M0 map in viewing orientation
                    imM0 = app.imageM0;
                    imM0 = permute(imM0,app.maskImageOrient(app.FitOrientationSpinner.Value,:));

                    % Image aspect ratio
                    aratio = CalcAspectratioFnc(app,app.T1XLabel.Text,app.T1YLabel.Text,size(imT1,2),size(imT1,3));

                    % Final export folder
                    finalGifExportFolder = strcat(app.gifExportFolder,filesep,"GIF",filesep);
                    
                    app.TextMessage('Exporting to GIF ...');
                    app.TextMessage(strcat("GIF export path = ",finalGifExportFolder));
                    
                    % Export GIF and app view
                    export_gif_3DT1(app,app.SEQparameters,finalGifExportFolder,imT1,imM0,app.SEQparameters.tag,app.MaxT1EditField.Value,app.t1Cmap,aratio);
                  
                    % Success
                    app.TextMessage('GIF export finished ...');
                    app.SetStatus(0);

                    % Reset buttons
                    app.SetLights({'Null','Null','Null','Null','Null','Null','Green'});
                    app.SetButtons({'on','on','on','on','on','on','on','on'});

                catch ME

                    % Failure
                    app.TextMessage(ME.message);
                    app.gifExportFolder = '';

                    % Reset buttons
                    app.SetLights({'Null','Null','Null','Null','Null','Null','Red'});
                    app.SetButtons({'on','on','on','on','on','on','on','on'});
                    app.SetStatus(2);

                end

            end
            
        end

        % Key press function: T1mappUIFigure
        function T1mappUIFigureKeyPress(app, event)
            
            key = event.Key;

            if contains(key,{'b','B'})
                app.dataType = 'b';
                app.TextMessage('Now reading B-type data ...');
            end

            if contains(key,{'m','M'})
                app.dataType = 'm';
                app.TextMessage('Now reading MR Solutions MRD file format ...');
            end

        end

        % Button pushed function: ECVLoadPreGdButton
        function ECVECVLoadPreGdButtonPushed(app, event)
    
            % Indicate busy
            app.SetStatus(3);
            app.DisableECVbuttons;
            buttons = app.GetButtons;
            app.SetButtons({'off','off','off','off','off','off','off','off'});
            lights = app.GetLights;

            app.validPreGdFlag = false;
            app.validECVmapFlag = false;
            app.imagePreGd = [];
            app.imageECV = [];
            app.ECVroiPoints = [];

            % Check standard smis folder first if platform is pc
            if ispc
                try
                    folderName = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','DataDirectory');
                    folderName = [folderName,filesep];
                    app.TextMessage('Data folder found in Windows registry ...');
                    app.TextMessage(strcat("Data folder = ",folderName," ..."));
                catch ME
                    app.TextMessage(ME.message);
                    app.TextMessage('Trying standard Data folder ...');
                    folderName = 'd:\smis\dev\Data\';
                end
                if exist(folderName, 'dir')
                    app.dataImportFolder = folderName;
                else
                    app.dataImportFolder = '';
                end
            end

            try

                % Select 1 folder with M0 and T1 nifti files

                g = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetdir in front
                dataFolder = uigetdir(app.dataImportFolder,"Select nifti base folder from T1mapp ...");
                delete(g);
                figure(app.T1mappUIFigure);

                % User cancel
                if ~ischar(dataFolder)
                    app.dataImportFolder = '';
                    ME = MException('T1mappD:userCancel','User cancel ...');
                    throw(ME);
                end

                % Search recursively
                M0File = dir(fullfile(dataFolder, '**', 'M0_map.nii'));
                T1File = dir(fullfile(dataFolder, '**', 'T1_map.nii'));

                % Read NIfTI files if found
                if ~isempty(M0File)
                    m0Path = fullfile(M0File(1).folder, M0File(1).name);
                    M0 = niftiread(m0Path);
                    M0info = niftiinfo(m0Path);
                else
                    app.TextMessage("ERROR: M0_map.nii not found ...");
                    ME = MException('T1mappD:fileNotFound','Cancel ...');
                    throw(ME);
                end

                if ~isempty(T1File)
                    t1Path = fullfile(T1File(1).folder, T1File(1).name);
                    T1 = niftiread(t1Path);
                    T1info = niftiinfo(t1Path);
                else
                    app.TextMessage("ERROR: T1_map.nii not found ...");
                    ME = MException('T1mappD:fileNotFound','Cancel ...');
                    throw(ME);
                end

                if ~isequal(size(M0),size(T1))
                    app.TextMessage("ERROR: M0 and T1 maps have unequal dimensions ...");
                    ME = MException('T1mappD:InvalidData','Cancel ...');
                    throw(ME);
                end

                if ndims(M0) < 2
                    app.TextMessage("ERROR: Invalid data dimensions ...");
                    ME = MException('T1mappD:InvalidData','Cancel ...');
                    throw(ME);
                end

                % Apply scaling
                if isfield(M0info.raw, 'scl_slope') && isfield(M0info.raw, 'scl_inter')
                    if M0info.raw.scl_slope ~= 0
                        M0 = M0 * M0info.raw.scl_slope + M0info.raw.scl_inter; %#ok<NASGU>
                        app.TextMessage("Correcting M0 nifti slope and intercept ...");
                    end
                end
                if isfield(T1info.raw, 'scl_slope') && isfield(T1info.raw, 'scl_inter')
                    if T1info.raw.scl_slope ~= 0
                        T1 = T1 * T1info.raw.scl_slope + T1info.raw.scl_inter;
                        app.TextMessage("Correcting T1 nifti slope and intercept ...");
                    end
                end

                % Pixel dimensions
                if isfield(T1info, 'PixelDimensions')
                    app.ECVparameters.FOV3D = T1info.PixelDimensions.*size(T1);
                else
                    app.TextMessage("ERROR: No pixel size information ...");
                    ME = MException('T1mappD:InvalidData','Cancel ...');
                    throw(ME);
                end

                app.ECVparameters.PHASE_ORIENTATION = 0;
                app.ECVparameters.NO_ECHOES = size(T1,4);
                app.ECVparameters.threeDdataPre = size(T1,3)>1;
                app.ECVparameters.multiFramePre = size(T1,4)>1;
                
                % Permute to [frames, x, y, z]
                T1 = flip(flip(T1,3),1);
                M0 = flip(flip(T1,3),1);
                T1 = permute(T1,[4,1,2,3]);
                M0 = permute(M0,[4,1,2,3]);

                % Cat in one variable
                app.imagePreGd = cat(5,T1,M0);
                app.imageGdInfo = T1info;

                % Check if both maps have equal dimensions
                if app.validPostGdFlag
                    if ~isequal(size(app.imagePreGd),size(app.imagePostGd))
                        app.TextMessage("WARNING: Pre and post contrast images have different sizes ...");
                        app.TextMessage("WARNING: Discarding post images ...");
                        app.validPostGdFlag = false;
                    end
                end

                % All good
                app.validPreGdFlag = true;
                app.TextMessage("Pre-contrast T1 maps loaded ...");
                app.SetStatus(0);

            catch ME

                % Not a valid data file
                app.TextMessage(ME.message);
                app.TextMessage("ERROR: loading pre-contrast T1 maps ...");
                app.SetStatus(2);

            end

            app.ECVOrientationSpinner.Value = 1;
            app.ECVOrientationSpinnerValueChanged;
            app.ECVColormapButtonGroupSelectionChanged;
              
            app.EnableECVbuttons;
            app.SetButtons(buttons);
            app.SetLights(lights);

        end

        % Button pushed function: ECVLoadPostGdButton
        function ECVECVLoadPostGdButtonPushed(app, event)

            % Indicate busy
            app.SetStatus(3);
            app.DisableECVbuttons;
            buttons = app.GetButtons;
            app.SetButtons({'off','off','off','off','off','off','off','off'});
            lights = app.GetLights;

            app.validPostGdFlag = false;
            app.validECVmapFlag = false;
            app.imagePostGd = [];
            app.imageECV = [];
            app.ECVroiPoints = [];

            % Check standard smis folder first if platform is pc
            if ispc
                try
                    folderName = winqueryreg('HKEY_LOCAL_MACHINE','Software\MRS\MRSClinicalUI\Scan','DataDirectory');
                    folderName = [folderName,filesep];
                    app.TextMessage('Data folder found in Windows registry ...');
                    app.TextMessage(strcat("Data folder = ",folderName," ..."));
                catch ME
                    app.TextMessage(ME.message);
                    app.TextMessage('Trying standard Data folder ...');
                    folderName = 'd:\smis\dev\Data\';
                end
                if exist(folderName, 'dir')
                    app.dataImportFolder = folderName;
                else
                    app.dataImportFolder = '';
                end
            end

            try

                % Select 1 folder with M0 and T1 nifti files

                g = figure('CloseRequestFcn','','Position',[-100 -100 0 0]); % Needed to bring uigetdir in front
                dataFolder = uigetdir(app.dataImportFolder,"Select nifti base folder from T1mapp ...");
                delete(g);
                figure(app.T1mappUIFigure);

                % User cancel
                if ~ischar(dataFolder)
                    app.dataImportFolder = '';
                    ME = MException('T1mappD:userCancel','User cancel ...');
                    throw(ME);
                end

                % Search recursively
                M0File = dir(fullfile(dataFolder, '**', 'M0_map.nii'));
                T1File = dir(fullfile(dataFolder, '**', 'T1_map.nii'));

                % Read NIfTI files if found
                if ~isempty(M0File)
                    m0Path = fullfile(M0File(1).folder, M0File(1).name);
                    M0 = niftiread(m0Path);
                    M0info = niftiinfo(m0Path);
                else
                    app.TextMessage("ERROR: M0_map.nii not found ...");
                    ME = MException('T1mappD:fileNotFound','Cancel ...');
                    throw(ME);
                end

                if ~isempty(T1File)
                    t1Path = fullfile(T1File(1).folder, T1File(1).name);
                    T1 = niftiread(t1Path);
                    T1info = niftiinfo(t1Path);
                else
                    app.TextMessage("ERROR: T1_map.nii not found ...");
                    ME = MException('T1mappD:fileNotFound','Cancel ...');
                    throw(ME);
                end

                if ~isequal(size(M0),size(T1))
                    app.TextMessage("ERROR: M0 and T1 maps have unequal dimensions ...");
                    ME = MException('T1mappD:InvalidData','Cancel ...');
                    throw(ME);
                end

                if ndims(M0) < 2
                    app.TextMessage("ERROR: Invalid data dimensions ...");
                    ME = MException('T1mappD:InvalidData','Cancel ...');
                    throw(ME);
                end

                % Apply scaling
                if isfield(M0info.raw, 'scl_slope') && isfield(M0info.raw, 'scl_inter')
                    if M0info.raw.scl_slope ~= 0
                        M0 = M0 * M0info.raw.scl_slope + M0info.raw.scl_inter; %#ok<NASGU>
                        app.TextMessage("Correcting M0 nifti slope and intercept ...");
                    end
                end
                if isfield(T1info.raw, 'scl_slope') && isfield(T1info.raw, 'scl_inter')
                    if T1info.raw.scl_slope ~= 0
                        T1 = T1 * T1info.raw.scl_slope + T1info.raw.scl_inter;
                        app.TextMessage("Correcting T1 nifti slope and intercept ...");
                    end
                end

                % Pixel dimensions
                if isfield(T1info, 'PixelDimensions')
                    app.ECVparameters.FOV3D = T1info.PixelDimensions.*size(T1);
                else
                    app.TextMessage("ERROR: No pixel size information ...");
                    ME = MException('T1mappD:InvalidData','Cancel ...');
                    throw(ME);
                end

                app.ECVparameters.PHASE_ORIENTATION = 0;
                app.ECVparameters.NO_ECHOES = size(T1,4);
                app.ECVparameters.threeDdataPost = size(T1,3)>1;
                app.ECVparameters.multiFramePost = size(T1,4)>1;

                % Permute to [frames, x, y, z]
                T1 = flip(flip(T1,3),1);
                M0 = flip(flip(T1,3),1);
                T1 = permute(T1,[4,1,2,3]);
                M0 = permute(M0,[4,1,2,3]);

                % Cat in one variable
                app.imagePostGd = cat(5,T1,M0);
               
                % Check if both maps have equal dimensions
                if app.validPreGdFlag
                    if ~isequal(size(app.imagePreGd),size(app.imagePostGd))
                        app.TextMessage("WARNING: Pre and post contrast images have different sizes ...");
                        app.TextMessage("WARNING: Discarding pre images ...");
                        app.validPreGdFlag = false;
                    end
                end
                 
                % All good
                app.validPostGdFlag = true;
                app.TextMessage("Post-contrast T1 maps loaded ...");
                app.SetStatus(0);
        
            catch ME

                % Not a valid data file
                app.TextMessage(ME.message);
                app.TextMessage("ERROR: loading post-contrast T1 maps ...");
                app.SetStatus(2);
        
            end

            app.ECVOrientationSpinner.Value = 1;
            app.ECVOrientationSpinnerValueChanged;
            app.ECVColormapButtonGroupSelectionChanged;

            app.EnableECVbuttons;
            app.SetButtons(buttons);
            app.SetLights(lights);

        end

        % Button pushed function: ECVExportButton
        function ECVExportNiftiButtonPushed(app, event)

            if app.validECVmapFlag

                try

                    % Indicate busy
                    app.SetStatus(3);
                    app.DisableECVbuttons;
                    buttons = app.GetButtons;
                    app.SetButtons({'off','off','off','off','off','off','off','off'});
                    lights = app.GetLights;

                    % Create new nifti
                    ECVinfo = app.imageGdInfo;
                   %ECVdata = single(flip(permute(app.imageECV, [3,2,4,1]), 3));
                    ECVdata = single(flip(flip(permute(app.imageECV, [2,3,4,1]),1),3));


                    % Nifti info
                    ECVinfo.AuxiliaryFile = ';MR;ECV-map';
                    ECVinfo.raw.aux_file = ';MR;ECV-map';
                    ECVinfo.Datatype = 'single';
                    ECVinfo.BitsPerPixel = 32;
                    ECVinfo.Filesize = 348 + numel(app.imageECV) * 4;
                    ECVinfo.Description = 'ECV-map;';
                    ECVinfo.DisplayIntensityRange = [0 100];
                    ECVinfo.raw.descrip = 'ECV-map;';
                    ECVinfo.raw.datatype = 8;
                    ECVinfo.raw.bitpix = 32;
                    ECVinfo.raw.qform_code = 1;

                    % T1 filename
                    ECVfilename = ECVinfo.Filename;

                    % Find all positions of 'T1'
                    matches = regexp(ECVfilename, 'T1');

                    % Replace last two occurrences (if they exist)
                    if ~isempty(matches)
                        numMatches = length(matches);
                        numToReplace = min(2, numMatches);
                        idxToReplace = matches(end-numToReplace+1:end);

                        for i = numToReplace:-1:1  % Replace from end to avoid shifting positions
                            idx = idxToReplace(i);
                            ECVfilename = [ECVfilename(1:idx-1), 'ECV', ECVfilename(idx+2:end)];
                        end
                    end

                    % New ECV file name
                    ECVinfo.Filename = ECVfilename;

                    % Ensure the directory exists
                    newdir = fileparts(ECVfilename);
                    if ~exist(newdir, 'dir')
                        mkdir(newdir);
                    end

                    % Write the file
                    niftiwrite(ECVdata, ECVfilename, ECVinfo);

                    app.TextMessage(strcat("Export folder/name = ",ECVfilename," ..."));
                    app.TextMessage("ECV images exported ...");
                    app.SetStatus(0);


                catch ME

                    % Something went wrong during NIFTI export
                    app.TextMessage(ME.message);
                    app.TextMessage("ERROR: during nifti export of ECV map ...");
                    app.SetStatus(2);

                end

                app.EnableECVbuttons;
                app.SetButtons(buttons);
                app.SetLights(lights);

            end

        end

        % Button pushed function: ECVEraseButton
        function ECVEraseButtonPushed(app, event)

            app.ECVroiPoints = [];
            app.PlotECVmaps;

        end

        % Value changed function: HematocritEditField, 
        % ...and 2 other components
        function ECVBloodParametersEditFieldValueChanged(app, event)
            
            app.PlotECVmaps;
            
        end

        % Button pushed function: ECVRegisterButton
        function ECVRegisterButtonPushed(app, event)

            if app.validPreGdFlag && app.validPostGdFlag

                app.TextMessage('Automatic registration of post Gd to pre Gd images ...');

                % Indicate busy
                app.AbortRegButton.Enable = 'on';
                app.SetStatus(3);
                app.DisableECVbuttons;
                buttons = app.GetButtons;
                app.SetButtons({'off','off','off','off','off','off','off','off'});
                lights = app.GetLights;

                % Initialize progress gauge
                app.RegistrationProgressGauge.Value = 0;

                try

                    % Register all slices to 1st echo time image
                    app.abortRegFlag = false;
                    registerECVimages(app);
                    app.PlotECVmaps;
                    app.TextMessage('Image registration is ready ...');
                    app.AbortRegButton.Enable = 'off';
                    app.SetStatus(0);
       
                catch ME

                    % Failure
                    app.TextMessage('ERROR: during image registration ...');
                    app.TextMessage(ME.message);
                    app.AbortRegButton.Enable = 'off';
                    app.SetStatus(2);
       
                end

                app.EnableECVbuttons;
                app.SetButtons(buttons);
                app.SetLights(lights);

            end

        end

        % Value changed function: ECVSliceEditField
        function ECVSliceEditFieldValueChanged(app, event)

            app.ECVSliceSlider.Value = app.ECVSliceEditField.Value;
            app.PlotECVmaps;

        end

        % Value changed function: ECVSliceSlider
        function ECVSliceSliderValueChanged(app, event)
            
            app.ECVSliceEditField.Value = round(app.ECVSliceSlider.Value);
            app.PlotECVmaps;

        end

        % Value changed function: ECVFrameEditField
        function ECVFrameEditFieldValueChanged(app, event)

            app.ECVFrameSlider.Value = app.ECVFrameEditField.Value;
            app.PlotECVmaps;

        end

        % Value changed function: ECVFrameSlider
        function ECVFrameSliderValueChanged(app, event)
            
            app.ECVFrameEditField.Value = round(app.ECVFrameSlider.Value);
            app.PlotECVmaps;
            
        end

        % Value changed function: ECVOrientationSpinner
        function ECVOrientationSpinnerValueChanged(app, event)
            
            if app.validPreGdFlag || app.validPostGdFlag

                if app.validPreGdFlag
                    im = app.imagePreGd(:,:,:,:,1);
                else
                    im = app.imagePostGd(:,:,:,:,1);
                end
              
                % reset the limits to the appropriate bounds
                app.ECVSliceEditField.Value = 1;
                app.ECVSliceSlider.Value = 1;
                app.ECVFrameEditField.Value = 1;
                app.ECVFrameSlider.Value = 1;

                noSlices = size(im,app.maskImageOrient(app.ECVOrientationSpinner.Value,4));
                noFrames = size(im,app.maskImageOrient(app.ECVOrientationSpinner.Value,1));

                if noSlices > 1

                    app.ECVSliceEditField.Limits = [1 noSlices];
                    app.ECVSliceSlider.Limits = [1 noSlices];
                    app.ECVSliceEditField.Value = round(noSlices/2);
                    app.ECVSliceSlider.Value = round(noSlices/2);
                    app.ECVSliceEditField.Enable = 'on';
                    app.ECVSliceEditFieldLabel.Enable = 'on';
                    app.ECVSliceSlider.Enable = 'on';
             
                else

                    app.ECVSliceEditField.Limits = [1 2];
                    app.ECVSliceSlider.Limits = [1 2];
                    app.ECVSliceEditField.Value = 1;
                    app.ECVSliceSlider.Value = 1;
                    app.ECVSliceEditField.Enable = 'off';
                    app.ECVSliceEditFieldLabel.Enable  = 'off';
                    app.ECVSliceSlider.Enable  = 'off';
             
                end

                if noFrames > 1

                    app.ECVFrameEditField.Value = 1;
                    app.ECVFrameEditField.Limits = [1 app.ECVparameters.NO_ECHOES];
                    app.ECVFrameSlider.Value = 1;
                    app.ECVFrameSlider.Limits = [1 app.ECVparameters.NO_ECHOES];
                    app.ECVFrameEditField.Enable = 'on';
                    app.ECVFrameEditFieldLabel.Enable = 'on';
                    app.ECVFrameSlider.Enable = 'on';

                else

                    app.ECVFrameEditField.Value = 1;
                    app.ECVFrameEditField.Limits = [1 2];
                    app.ECVFrameSlider.Value = 1;
                    app.ECVFrameSlider.Limits = [1 2];
                    app.ECVFrameEditField.Enable = 'off';
                    app.ECVFrameEditFieldLabel.Enable = 'off';
                    app.ECVFrameSlider.Enable = 'off';

                end
               
                % plot the image
                app.PlotECVmaps;
                
             end

            
        end

        % Selection changed function: ECVColormapButtonGroup
        function ECVColormapButtonGroupSelectionChanged(app, event)
   
            if app.ECVgrayButton.Value == 1
                app.ECVCmap = gray;
            end
            
            if app.ECVautumnButton.Value == 1
                app.ECVCmap = autumn;
            end
            
            if app.ECVwinterButton.Value == 1
                app.ECVCmap = winter;
            end
            
            if app.ECVparulaButton.Value == 1
                app.ECVCmap = parula;
            end
            
            if app.ECVlipariButton.Value == 1
                app.ECVCmap = lipariColorMap();
            end
            
            % Background should always be black
            app.ECVCmap(1,:) = [0 0 0];
            
            % Plot the maps with the new color map
            app.PlotECVmaps;
            
        end

        % Image clicked function: PostGdTransImage, PreGdTransImage
        function ECVPrePostGdImageClicked(app, event)

            try

                if app.validPreGdFlag

                    % Get the Position of the GUI and the position of the mouse click
                    set(0,'units','pixels');
                    guiposition = get(app.T1mappUIFigure,'Position');
                    cursorposition = get(0,'PointerLocation');

                    % Position of the cursor click in the image window
                    imagepos = [743,449]; % position of bottom-left corner of image in the app ~ (745,449)
                    Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                    Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

                    Xpos(Xpos>311) = Xpos-311;

                    % Image orientation and aspect ratio
                    if strcmp(app.PreGdXLabel.Text,'X') && strcmp(app.PreGdYLabel.Text,'Y') && app.ECVparameters.PHASE_ORIENTATION
                        px = app.maskImageOrient(app.ECVOrientationSpinner.Value,3);
                        py = app.maskImageOrient(app.ECVOrientationSpinner.Value,2);
                        pz = app.maskImageOrient(app.ECVOrientationSpinner.Value,4);
                    else
                        px = app.maskImageOrient(app.ECVOrientationSpinner.Value,2);
                        py = app.maskImageOrient(app.ECVOrientationSpinner.Value,3);
                        pz = app.maskImageOrient(app.ECVOrientationSpinner.Value,4);
                    end

                    aratio = app.ECVparameters.FOV3D(px-1)/app.ECVparameters.FOV3D(py-1);
                    xsize = size(app.imagePreGd,px);
                    ysize = size(app.imagePreGd,py);

                    % Correct for non-square image
                    imsize = 289;
                    if aratio >= 1
                        xcor = (Xpos/imsize) * xsize;
                        ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                    else
                        xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                        ycor = (Ypos/imsize) * ysize;
                    end

                    % Round to nearest integer
                    xcor = round(xcor);
                    ycor = round(ycor);

                    % Keep the values within matrix boundaries
                    xcor(xcor<1) = 1;
                    ycor(ycor<1) = 1;
                    xcor(xcor>xsize) = xsize;
                    ycor(ycor>ysize) = ysize;

                    % Current slice and time frame
                    zcor = app.ECVSliceEditField.Value;
                    timeFrame = app.ECVFrameEditField.Value;

                    % Inverse orientation
                    pi = [1 px py pz];
                    pi = pi(:,pi);

                    % Include points within Radius
                    se = strel("disk",app.ECVBrushEditField.Value-1);
                    [nzrow,nzcol] = find(se.Neighborhood==1);
                    nzc = [nzrow,nzcol] - [app.ECVBrushEditField.Value,app.ECVBrushEditField.Value];

                    % Paint ROI
                    for i = 1:size(nzc,1)
                        if (xcor+nzc(i,1)>0) && (ycor+nzc(i,2)>0) && (xcor+nzc(i,1)<=xsize) && (ycor+nzc(i,2)<=ysize)
                            addPoint = [timeFrame xcor+nzc(i,1) ycor+nzc(i,2) zcor];
                            addPoint = addPoint(:,pi);
                            app.ECVroiPoints = [app.ECVroiPoints; addPoint];
                        end
                    end
                    app.ECVroiPoints = unique(app.ECVroiPoints,'rows');
                    app.PlotECVPoints;

                    % Determine mean T1 in pre Gd ROI
                    for i = 1:size(app.ECVroiPoints,1)
                        if strcmp(app.PreGdXLabel.Text,'X') && strcmp(app.PreGdYLabel.Text,'Y') && app.ECVparameters.PHASE_ORIENTATION
                            t1values(i) = app.imagePreGd(app.ECVroiPoints(i,1),ysize-app.ECVroiPoints(i,3)+1,app.ECVroiPoints(i,2),app.ECVroiPoints(i,4),1);
                        else
                            t1values(i) = app.imagePreGd(app.ECVroiPoints(i,1),app.ECVroiPoints(i,2),app.ECVroiPoints(i,3),app.ECVroiPoints(i,4),1);
                        end
                    end

                    r1values = 1./double(t1values);
                    r1values = nonzeros(r1values);
                    r1values(isnan(r1values)) = [];
                    r1values(isinf(r1values)) = [];
                    t1value = 1/mean(r1values);                         % T1 = 1 / mean(R1 values in pixels)
                    t1value(t1value>5000) = 5000;
                    t1value(t1value<1) = 1;
                    t1value = round(t1value,1);

                    app.T1preBloodEditField.Value = t1value;

                end

                if app.validPostGdFlag

                    % Get the Position of the GUI and the position of the mouse click
                    set(0,'units','pixels');
                    guiposition = get(app.T1mappUIFigure,'Position');
                    cursorposition = get(0,'PointerLocation');

                    % Position of the cursor click in the image window
                    imagepos = [743,449]; % position of bottom-left corner of image in the app ~ (745,449)
                    Xpos = cursorposition(1)-guiposition(1)-imagepos(1);
                    Ypos = cursorposition(2)-guiposition(2)-imagepos(2);

                    Xpos(Xpos>311) = Xpos-311;

                    % Image orientation and aspect ratio
                    if strcmp(app.PostGdXLabel.Text,'X') && strcmp(app.PostGdYLabel.Text,'Y') && app.ECVparameters.PHASE_ORIENTATION
                        px = app.maskImageOrient(app.ECVOrientationSpinner.Value,3);
                        py = app.maskImageOrient(app.ECVOrientationSpinner.Value,2);
                        pz = app.maskImageOrient(app.ECVOrientationSpinner.Value,4);
                    else
                        px = app.maskImageOrient(app.ECVOrientationSpinner.Value,2);
                        py = app.maskImageOrient(app.ECVOrientationSpinner.Value,3);
                        pz = app.maskImageOrient(app.ECVOrientationSpinner.Value,4);
                    end

                    aratio = app.ECVparameters.FOV3D(px-1)/app.ECVparameters.FOV3D(py-1);
                    xsize = size(app.imagePreGd,px);
                    ysize = size(app.imagePreGd,py);

                    % Correct for non-square image
                    imsize = 289;
                    if aratio >= 1
                        xcor = (Xpos/imsize) * xsize;
                        ycor = (Ypos/imsize) * ysize * aratio - (0.5 * (aratio-1) * ysize);
                    else
                        xcor = (Xpos/imsize) * xsize * (1/aratio) - (0.5 * ((1/aratio)-1) * xsize);
                        ycor = (Ypos/imsize) * ysize;
                    end

                    % Round to nearest integer
                    xcor = round(xcor);
                    ycor = round(ycor);

                    % Keep the values within matrix boundaries
                    xcor(xcor<1) = 1;
                    ycor(ycor<1) = 1;
                    xcor(xcor>xsize) = xsize;
                    ycor(ycor>ysize) = ysize;

                    % Current slice and time frame
                    zcor = app.ECVSliceEditField.Value;
                    timeFrame = app.ECVFrameEditField.Value;

                    % Inverse orientation
                    pi = [1 px py pz];
                    pi = pi(:,pi);

                    % Include points within Radius
                    se = strel("disk",app.ECVBrushEditField.Value-1);
                    [nzrow,nzcol] = find(se.Neighborhood==1);
                    nzc = [nzrow,nzcol] - [app.ECVBrushEditField.Value,app.ECVBrushEditField.Value];

                    % Paint ROI
                    for i = 1:size(nzc,1)
                        if (xcor+nzc(i,1)>0) && (ycor+nzc(i,2)>0) && (xcor+nzc(i,1)<=xsize) && (ycor+nzc(i,2)<=ysize)
                            addPoint = [timeFrame xcor+nzc(i,1) ycor+nzc(i,2) zcor];
                            addPoint = addPoint(:,pi);
                            app.ECVroiPoints = [app.ECVroiPoints; addPoint];
                        end
                    end
                    app.ECVroiPoints = unique(app.ECVroiPoints,'rows');
                    app.PlotECVPoints;

                    % Determine mean T1 in pre Gd ROI
                    for i = 1:size(app.ECVroiPoints,1)
                        if strcmp(app.PreGdXLabel.Text,'X') && strcmp(app.PreGdYLabel.Text,'Y') && app.ECVparameters.PHASE_ORIENTATION
                            t1values(i) = app.imagePostGd(app.ECVroiPoints(i,1),ysize-app.ECVroiPoints(i,3)+1,app.ECVroiPoints(i,2),app.ECVroiPoints(i,4),1);
                        else
                            t1values(i) = app.imagePostGd(app.ECVroiPoints(i,1),app.ECVroiPoints(i,2),app.ECVroiPoints(i,3),app.ECVroiPoints(i,4),1);
                        end
                    end

                    r1values = 1./double(t1values);
                    r1values = nonzeros(r1values);
                    r1values(isnan(r1values)) = [];
                    r1values(isinf(r1values)) = [];
                    t1value = 1/mean(r1values);                         % T1 = 1 / mean(R1 values in pixels)
                    t1value(t1value>5000) = 5000;
                    t1value(t1value<1) = 1;
                    t1value = round(t1value,1);

                    app.T1postBloodEditField.Value = t1value;

                end

                app.PlotECVmaps;

            catch ME

                app.TextMessage('ERROR: Something went wrong during ROI selection ...');
                app.TextMessage(ME.message);

            end

        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            
            % Remove subfolders from search folder
            if (~isdeployed)
                info = dir(which('T1mappD.mlapp'));
                app.appFolder = info.folder;
                rmpath(genpath(app.appFolder));
                cd(app.oldFolder);
            end
            
            % Exit
            app.delete;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.T1mappUIFigure = uifigure('Visible', 'off');
            app.T1mappUIFigure.Name = 'T1mapp';
            app.T1mappUIFigure.Icon = fullfile(pathToMLAPP, 'T1icon.png');
            app.T1mappUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @T1mappUIFigureWindowScrollWheel, true);
            app.T1mappUIFigure.KeyPressFcn = createCallbackFcn(app, @T1mappUIFigureKeyPress, true);
            app.TitleLabel = uilabel(app.T1mappUIFigure);
            app.TitleLabel.Text = '3D RF-spoiled gradient-echo T1 mapping';
            app.CreditsLabel = uilabel(app.T1mappUIFigure);
            app.CreditsLabel.Tooltip = {'g.j.strijkers@amsterdamumc.nl'};
            app.CreditsLabel.Text = 'Gustav Strijkers, Amsterdam UMC, Version X.X.X';
            app.TulpImage = uiimage(app.T1mappUIFigure);
            app.MRSImage = uiimage(app.T1mappUIFigure);
            app.DashboardTabGroup = uitabgroup(app.T1mappUIFigure);
            app.DashboardTab = uitab(app.DashboardTabGroup);
            app.DashboardTab.Title = 'Dashboard';
            app.LoadMRDButton = uibutton(app.DashboardTab, 'push');
            app.LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMRDButtonPushed, true);
            app.LoadMRDButton.Tooltip = {'Load MRD data'};
            app.LoadMRDButton.Text = 'Load MRD';
            app.RegisterButton = uibutton(app.DashboardTab, 'push');
            app.RegisterButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterButtonPushed, true);
            app.RegisterButton.Enable = 'off';
            app.RegisterButton.Tooltip = {'Register variable flip-angle images'};
            app.RegisterButton.Text = 'Register';
            app.MaskButton = uibutton(app.DashboardTab, 'push');
            app.MaskButton.ButtonPushedFcn = createCallbackFcn(app, @MaskButtonPushed, true);
            app.MaskButton.Enable = 'off';
            app.MaskButton.Tooltip = {'Mask the image to exclude background pixels during fitting'};
            app.MaskButton.Text = 'Mask';
            app.RegistrationProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.RegistrationProgressGauge.MajorTicks = [0 25 50 75 100];
            app.FitT1Button = uibutton(app.DashboardTab, 'push');
            app.FitT1Button.ButtonPushedFcn = createCallbackFcn(app, @FitT1ButtonPushed, true);
            app.FitT1Button.Enable = 'off';
            app.FitT1Button.Tooltip = {'Do the T1 fitting'};
            app.FitT1Button.Text = 'Fit T1';
            app.DCMNIFTIButton = uibutton(app.DashboardTab, 'push');
            app.DCMNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportDCMNIFTIButtonPushed, true);
            app.DCMNIFTIButton.Enable = 'off';
            app.DCMNIFTIButton.Tooltip = {'Export to Dicom and Nifti'};
            app.DCMNIFTIButton.Text = 'DCM / NIFTI';
            app.ExportNIFTIButton = uibutton(app.DashboardTab, 'push');
            app.ExportNIFTIButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNIFTIButtonPushed, true);
            app.ExportNIFTIButton.Enable = 'off';
            app.ExportNIFTIButton.Visible = 'off';
            app.ExportNIFTIButton.Tooltip = {'Export to NIFTI'};
            app.ExportNIFTIButton.Text = 'Export NIFTI';
            app.GIFButton = uibutton(app.DashboardTab, 'push');
            app.GIFButton.ButtonPushedFcn = createCallbackFcn(app, @ExportGIFButtonPushed, true);
            app.GIFButton.Enable = 'off';
            app.GIFButton.Tooltip = {'Export to GIF'};
            app.GIFButton.Text = 'GIF';
            app.ExitButton = uibutton(app.DashboardTab, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tooltip = {'Exit the application'};
            app.ExitButton.Text = '    Exit';
            app.FitProgressGauge = uigauge(app.DashboardTab, 'linear');
            app.FitProgressGauge.MajorTicks = [0 25 50 75 100];
            app.AbortFitButton = uibutton(app.DashboardTab, 'push');
            app.AbortFitButton.ButtonPushedFcn = createCallbackFcn(app, @AbortFitButtonPushed, true);
            app.AbortFitButton.Enable = 'off';
            app.AbortFitButton.Tooltip = {'Abort the T1 fitting'};
            app.AbortFitButton.Text = 'Abort';
            app.Lamp1 = uilamp(app.DashboardTab);
            app.Lamp2 = uilamp(app.DashboardTab);
            app.Lamp3 = uilamp(app.DashboardTab);
            app.Lamp4 = uilamp(app.DashboardTab);
            app.Lamp5 = uilamp(app.DashboardTab);
            app.Lamp6 = uilamp(app.DashboardTab);
            app.Lamp6.Enable = 'off';
            app.Lamp6.Visible = 'off';
            app.Lamp7 = uilamp(app.DashboardTab);
            app.EstimatedFitTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedFitTimeViewField.Editable = 'off';
            app.RegistrationDropDown = uidropdown(app.DashboardTab);
            app.RegistrationDropDown.Items = {'Translation', 'Rigid', 'Affine', 'B-Spline'};
            app.RegistrationDropDown.Enable = 'off';
            app.RegistrationDropDown.Tooltip = {'Registration transformation method'};
            app.RegistrationDropDown.Value = 'Translation';
            app.AbortRegButton = uibutton(app.DashboardTab, 'push');
            app.AbortRegButton.ButtonPushedFcn = createCallbackFcn(app, @AbortRegButtonPushed, true);
            app.AbortRegButton.Enable = 'off';
            app.AbortRegButton.Tooltip = {'Abort the registration'};
            app.AbortRegButton.Text = 'Abort';
            app.EstimatedRegTimeViewField = uieditfield(app.DashboardTab, 'text');
            app.EstimatedRegTimeViewField.Editable = 'off';
            app.MessagesTabGroup = uitabgroup(app.T1mappUIFigure);
            app.MessagesTab = uitab(app.MessagesTabGroup);
            app.MessagesTab.Title = 'Messages';
            app.MessageBox = uitextarea(app.MessagesTab);
            app.MessageBox.Editable = 'off';
            app.MessageBox.Tooltip = {'Messages'};
            app.MessageBox.Value = {'Messages ...'};
            app.BackgroundBox = uitextarea(app.MessagesTab);
            app.BackgroundBox.Editable = 'off';
            app.BackgroundBox.Tooltip = {'App status'};
            app.StatusImage = uiimage(app.MessagesTab);
            app.StatusLabel = uilabel(app.MessagesTab);
            app.StatusLabel.Text = 'STATUS';
            app.SequenceParametersTabGroup = uitabgroup(app.T1mappUIFigure);
            app.SequenceParametersTab = uitab(app.SequenceParametersTabGroup);
            app.SequenceParametersTab.Title = 'Sequence parameters';
            app.MatrixViewField1 = uieditfield(app.SequenceParametersTab, 'numeric');
            app.MatrixViewField1.ValueDisplayFormat = '%.0f';
            app.MatrixViewField1.Editable = 'off';
            app.MatrixViewField1.Tooltip = {'Readout matrix size'};
            app.MatrixViewField2 = uieditfield(app.SequenceParametersTab, 'numeric');
            app.MatrixViewField2.ValueDisplayFormat = '%.0f';
            app.MatrixViewField2.Editable = 'off';
            app.MatrixViewField2.Tooltip = {'2nd phase-encoding matrix size'};
            app.MatrixViewField3 = uieditfield(app.SequenceParametersTab, 'numeric');
            app.MatrixViewField3.ValueDisplayFormat = '%.0f';
            app.MatrixViewField3.Editable = 'off';
            app.MatrixViewField3.Tooltip = {'Number of slices / 2nd phase-encoding matrix size'};
            app.MatrixViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.MatrixViewFieldLabel.Text = 'Matrix';
            app.FOVViewField1 = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FOVViewField1.Limits = [0 Inf];
            app.FOVViewField1.ValueDisplayFormat = '%.1f';
            app.FOVViewField1.Editable = 'off';
            app.FOVViewField1.Tooltip = {'FOV readout direction'};
            app.FOVViewField2 = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FOVViewField2.Limits = [0 Inf];
            app.FOVViewField2.ValueDisplayFormat = '%.1f';
            app.FOVViewField2.Editable = 'off';
            app.FOVViewField2.Tooltip = {'FOV phase-encoding direction'};
            app.FOVViewField3 = uieditfield(app.SequenceParametersTab, 'numeric');
            app.FOVViewField3.ValueDisplayFormat = '%.1f';
            app.FOVViewField3.Editable = 'off';
            app.FOVViewField3.Tooltip = {'Slice thickness / FOV 2nd phase-encoding direction'};
            app.FOVViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.FOVViewFieldLabel.Text = 'FOV (mm)';
            app.XLabelFOV = uilabel(app.SequenceParametersTab);
            app.XLabelFOV.Text = 'X';
            app.YLabelFOV = uilabel(app.SequenceParametersTab);
            app.YLabelFOV.Text = 'Y';
            app.ZLabelFOV = uilabel(app.SequenceParametersTab);
            app.ZLabelFOV.Text = 'Z';
            app.SequenceViewField = uieditfield(app.SequenceParametersTab, 'text');
            app.SequenceViewField.Editable = 'off';
            app.SequenceViewField.Tooltip = {'MRI sequence'};
            app.SequenceViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.SequenceViewFieldLabel.Text = 'Sequence';
            app.TRViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TRViewField.Editable = 'off';
            app.TRViewField.Tooltip = {'Repetition time'};
            app.TRViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.TRViewFieldLabel.Tooltip = {'Repetition time'};
            app.TRViewFieldLabel.Text = 'TR (ms)';
            app.TEViewField = uieditfield(app.SequenceParametersTab, 'numeric');
            app.TEViewField.Editable = 'off';
            app.TEViewField.Tooltip = {'Echo time'};
            app.TEViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.TEViewFieldLabel.Tooltip = {'Echo time'};
            app.TEViewFieldLabel.Text = 'TE (ms)';
            app.FAsViewField = uieditfield(app.SequenceParametersTab, 'text');
            app.FAsViewField.Editable = 'off';
            app.FAsViewField.Tooltip = {'Flip angle(s) (degrees)'};
            app.FAsViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.FAsViewFieldLabel.Tooltip = {'Flip angle(s) (degrees)'};
            app.FAsViewFieldLabel.Text = 'Flip angles';
            app.FAsIncludedViewField = uieditfield(app.SequenceParametersTab, 'text');
            app.FAsIncludedViewField.ValueChangedFcn = createCallbackFcn(app, @FAsIncludedViewFieldValueChanged, true);
            app.FAsIncludedViewField.Tooltip = {'Included flip angles'};
            app.FAsIncludedViewField.Value = '1';
            app.FAsIncludedViewFieldLabel = uilabel(app.SequenceParametersTab);
            app.FAsIncludedViewFieldLabel.Tooltip = {'Flip angle(s) (degrees)'};
            app.FAsIncludedViewFieldLabel.Text = 'Include';
            app.RawImagesTabGroup = uitabgroup(app.T1mappUIFigure);
            app.RawimagesTab = uitab(app.RawImagesTabGroup);
            app.RawimagesTab.Title = 'Raw images';
            app.RawImage = uiaxes(app.RawimagesTab);
            app.RawImage.XTick = [];
            app.RawImage.YTick = [];
            app.ImageSelectionPanel = uipanel(app.RawimagesTab);
            app.ImageSelectionPanel.Title = 'Image selection';
            app.SliceEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.SliceEditField.Limits = [1 256];
            app.SliceEditField.ValueDisplayFormat = '%.0f';
            app.SliceEditField.ValueChangedFcn = createCallbackFcn(app, @SliceEditFieldValueChanged, true);
            app.SliceEditField.Tooltip = {'Slice number'};
            app.SliceEditField.Value = 1;
            app.SliceSlider = uislider(app.ImageSelectionPanel);
            app.SliceSlider.Limits = [1 256];
            app.SliceSlider.MajorTicks = [];
            app.SliceSlider.ValueChangedFcn = createCallbackFcn(app, @SliceSliderValueChanged, true);
            app.SliceSlider.MinorTicks = [1 16 31 46 61 76 91 106 121 136 151 166 181 196 211 226 241 256];
            app.SliceSlider.Tooltip = {'Slice number slider'};
            app.SliceSlider.Value = 1;
            app.FlipAngleEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.FlipAngleEditField.Limits = [1 256];
            app.FlipAngleEditField.ValueDisplayFormat = '%.0f';
            app.FlipAngleEditField.ValueChangedFcn = createCallbackFcn(app, @FlipAngleEditFieldValueChanged, true);
            app.FlipAngleEditField.Tooltip = {'Flip-angle number'};
            app.FlipAngleEditField.Value = 1;
            app.FlipAngleSlider = uislider(app.ImageSelectionPanel);
            app.FlipAngleSlider.Limits = [1 256];
            app.FlipAngleSlider.MajorTicks = [];
            app.FlipAngleSlider.ValueChangedFcn = createCallbackFcn(app, @FlipAngleSliderValueChanged, true);
            app.FlipAngleSlider.MinorTicks = [1 16 31 46 61 76 91 106 121 136 151 166 181 196 211 226 241 256];
            app.FlipAngleSlider.Tooltip = {'Flip-angle number slider'};
            app.FlipAngleSlider.Value = 1;
            app.FrameEditField = uieditfield(app.ImageSelectionPanel, 'numeric');
            app.FrameEditField.Limits = [1 256];
            app.FrameEditField.ValueDisplayFormat = '%.0f';
            app.FrameEditField.ValueChangedFcn = createCallbackFcn(app, @FrameEditFieldValueChanged, true);
            app.FrameEditField.Tooltip = {'Frame number'};
            app.FrameEditField.Value = 1;
            app.FrameSlider = uislider(app.ImageSelectionPanel);
            app.FrameSlider.Limits = [1 256];
            app.FrameSlider.MajorTicks = [];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.MinorTicks = [1 16 31 46 61 76 91 106 121 136 151 166 181 196 211 226 241 256];
            app.FrameSlider.Tooltip = {'Frame number slider'};
            app.FrameSlider.Value = 1;
            app.OrientationSpinner = uispinner(app.ImageSelectionPanel);
            app.OrientationSpinner.Limits = [1 3];
            app.OrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @OrientationSpinnerValueChanged, true);
            app.OrientationSpinner.Tooltip = {'Image orientation'};
            app.OrientationSpinner.Value = 1;
            app.SliceEditFieldLabel = uilabel(app.ImageSelectionPanel);
            app.SliceEditFieldLabel.Text = 'Slice';
            app.FlipAngleEditFieldLabel = uilabel(app.ImageSelectionPanel);
            app.FlipAngleEditFieldLabel.Text = 'flip-angle';
            app.FrameEditFieldLabel = uilabel(app.ImageSelectionPanel);
            app.FrameEditFieldLabel.Text = 'frame';
            app.OrientationSpinnerLabel = uilabel(app.ImageSelectionPanel);
            app.OrientationSpinnerLabel.Text = 'Orientation';
            app.MaskSettingsPanel = uipanel(app.RawimagesTab);
            app.MaskSettingsPanel.Title = 'Mask settings';
            app.ThresholdEditFieldLabel = uilabel(app.MaskSettingsPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.MaskSettingsPanel, 'numeric');
            app.ThresholdEditField.Limits = [0 Inf];
            app.ThresholdEditField.ValueDisplayFormat = '%.0f';
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Tooltip = {'Mask threshold'};
            app.ThresholdSlider = uislider(app.MaskSettingsPanel);
            app.ThresholdSlider.Limits = [1 32676];
            app.ThresholdSlider.MajorTicks = [];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ThresholdSlider.MinorTicks = [1 2001 4001 6001 8001 10001 12001 14001 16001 18001 20001 22001 24001 26001 28001 30001 32676];
            app.ThresholdSlider.Tooltip = {'Mask threshold'};
            app.ThresholdSlider.Value = 1;
            app.ErodeButton = uibutton(app.MaskSettingsPanel, 'push');
            app.ErodeButton.ButtonPushedFcn = createCallbackFcn(app, @ErodeButtonPushed, true);
            app.ErodeButton.Tooltip = {'Mask erosion'};
            app.ErodeButton.Text = 'Erode';
            app.DilateButton = uibutton(app.MaskSettingsPanel, 'push');
            app.DilateButton.ButtonPushedFcn = createCallbackFcn(app, @DilateButtonPushed, true);
            app.DilateButton.Tooltip = {'Mask dilation'};
            app.DilateButton.Text = 'Dilate';
            app.CloseButton = uibutton(app.MaskSettingsPanel, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Tooltip = {'Mask closing'};
            app.CloseButton.Text = 'Close';
            app.OpenButton = uibutton(app.MaskSettingsPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Mask opening'};
            app.OpenButton.Text = 'Open';
            app.ImageLabel = uilabel(app.RawimagesTab);
            app.ImageLabel.Text = 'Image';
            app.XLabel = uilabel(app.RawimagesTab);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.RawimagesTab);
            app.YLabel.Text = 'Y';
            app.Image0 = uiimage(app.RawimagesTab);
            app.WindowSlider = uislider(app.RawimagesTab);
            app.WindowSlider.Limits = [0 32768];
            app.WindowSlider.MajorTicks = [];
            app.WindowSlider.ValueChangedFcn = createCallbackFcn(app, @WindowSliderValueChanged, true);
            app.WindowSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.WindowSlider.Tooltip = {'Window'};
            app.WindowSlider.Value = 16384;
            app.WindowEditFieldLabel = uilabel(app.RawimagesTab);
            app.WindowEditFieldLabel.Text = 'W';
            app.WindowEditField = uieditfield(app.RawimagesTab, 'numeric');
            app.WindowEditField.Limits = [0 Inf];
            app.WindowEditField.ValueDisplayFormat = '%.0f';
            app.WindowEditField.ValueChangedFcn = createCallbackFcn(app, @WindowEditFieldValueChanged, true);
            app.WindowEditField.Tooltip = {'Window'};
            app.LevelSlider = uislider(app.RawimagesTab);
            app.LevelSlider.Limits = [0 32768];
            app.LevelSlider.MajorTicks = [];
            app.LevelSlider.ValueChangedFcn = createCallbackFcn(app, @LevelSliderValueChanged, true);
            app.LevelSlider.MinorTicks = [0 2048 4096 6144 8192 10240 12288 14336 16384 18432 20480 22528 24576 26624 28672 30720 32768];
            app.LevelSlider.Tooltip = {'Level'};
            app.LevelSlider.Value = 16384;
            app.LevelEditFieldLabel = uilabel(app.RawimagesTab);
            app.LevelEditFieldLabel.Text = 'L';
            app.LevelEditField = uieditfield(app.RawimagesTab, 'numeric');
            app.LevelEditField.Limits = [0 Inf];
            app.LevelEditField.ValueDisplayFormat = '%.0f';
            app.LevelEditField.ValueChangedFcn = createCallbackFcn(app, @LevelEditFieldValueChanged, true);
            app.LevelEditField.Tooltip = {'Level'};
            app.AutoLWButton = uibutton(app.RawimagesTab, 'state');
            app.AutoLWButton.ValueChangedFcn = createCallbackFcn(app, @AutoLWButtonValueChanged, true);
            app.AutoLWButton.Tooltip = {'Automatic window and level setting'};
            app.AutoLWButton.Text = 'Auto';
            app.AutoLWButton.Value = true;
            app.B1Tab = uitab(app.RawImagesTabGroup);
            app.B1Tab.Title = 'B1';
            app.B1map = uiaxes(app.B1Tab);
            app.B1map.XTick = [];
            app.B1map.YTick = [];
            app.B1SliceEditField = uieditfield(app.B1Tab, 'numeric');
            app.B1SliceEditField.Limits = [1 256];
            app.B1SliceEditField.ValueDisplayFormat = '%.0f';
            app.B1SliceEditField.ValueChangedFcn = createCallbackFcn(app, @B1SliceEditFieldValueChanged, true);
            app.B1SliceEditField.Tooltip = {'Slice number'};
            app.B1SliceEditField.Value = 1;
            app.B1SliceSlider = uislider(app.B1Tab);
            app.B1SliceSlider.Limits = [1 256];
            app.B1SliceSlider.MajorTicks = [];
            app.B1SliceSlider.ValueChangedFcn = createCallbackFcn(app, @B1SliceSliderValueChanged, true);
            app.B1SliceSlider.MinorTicks = [1 16 31 46 61 76 91 106 121 136 151 166 181 196 211 226 241 256];
            app.B1SliceSlider.Tooltip = {'Slice number slider'};
            app.B1SliceSlider.Value = 1;
            app.B1SliceEditFieldLabel = uilabel(app.B1Tab);
            app.B1SliceEditFieldLabel.Text = 'Slice';
            app.B1LoadMRDButton = uibutton(app.B1Tab, 'push');
            app.B1LoadMRDButton.ButtonPushedFcn = createCallbackFcn(app, @LoadB1MRDButtonPushed, true);
            app.B1LoadMRDButton.Tooltip = {'Load B1 map'};
            app.B1LoadMRDButton.Text = 'Load B1 MRD';
            app.B1MapCheckBox = uicheckbox(app.B1Tab);
            app.B1MapCheckBox.Tooltip = {'Check to use B1 map correction'};
            app.B1MapCheckBox.Text = 'Use B1 map';
            app.B1OrientationSpinnerLabel = uilabel(app.B1Tab);
            app.B1OrientationSpinnerLabel.Text = 'Orientation';
            app.B1OrientationSpinner = uispinner(app.B1Tab);
            app.B1OrientationSpinner.Limits = [1 3];
            app.B1OrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @B1OrientationSpinnerValueChanged, true);
            app.B1OrientationSpinner.Tooltip = {'Image orientation'};
            app.B1OrientationSpinner.Value = 1;
            app.B1YLabel = uilabel(app.B1Tab);
            app.B1YLabel.Text = 'Y';
            app.B1XLabel = uilabel(app.B1Tab);
            app.B1XLabel.Text = 'X';
            app.B1Image = uiimage(app.B1Tab);
            app.AppSettingsTab = uitab(app.RawImagesTabGroup);
            app.AppSettingsTab.Title = 'App settings';
            app.ColorsPanel = uipanel(app.AppSettingsTab);
            app.ColorsPanel.Title = 'Colors';
            app.ColorsButtonGroup = uibuttongroup(app.ColorsPanel);
            app.ColorsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColorsButtonGroupSelectionChanged, true);
            app.ColorsButtonGroup.Tooltip = {'App color scheme selection'};
            app.ClassicButton = uiradiobutton(app.ColorsButtonGroup);
            app.ClassicButton.Text = 'classic';
            app.ClassicButton.Interpreter = 'html';
            app.BlackWhiteButton = uiradiobutton(app.ColorsButtonGroup);
            app.BlackWhiteButton.Text = 'black/white';
            app.BlackWhiteButton.Interpreter = 'html';
            app.BlackWhiteButton.Value = true;
            app.SoundsPanel = uipanel(app.AppSettingsTab);
            app.SoundsPanel.Title = 'Sounds';
            app.AutoSoundsCheckBox = uicheckbox(app.SoundsPanel);
            app.AutoSoundsCheckBox.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBox.Text = '';
            app.AutoSoundsCheckBox.Value = true;
            app.AutoSoundsCheckBoxLabel = uilabel(app.SoundsPanel);
            app.AutoSoundsCheckBoxLabel.Tooltip = {'Warning and error sounds on or off'};
            app.AutoSoundsCheckBoxLabel.Text = 'sounds on/off';
            app.FitResultsTabGroup = uitabgroup(app.T1mappUIFigure);
            app.FitResultsTab = uitab(app.FitResultsTabGroup);
            app.FitResultsTab.Title = 'T1 maps';
            app.T1FitFig = uiaxes(app.FitResultsTab);
            xlabel(app.T1FitFig, 'α')
            ylabel(app.T1FitFig, 'S')
            app.T1FitFig.XLim = [0 100];
            app.T1FitFig.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.T1FitFig.YTick = [];
            app.DespotT1FitFig = uiaxes(app.FitResultsTab);
            xlabel(app.DespotT1FitFig, 'S / tan(α)')
            ylabel(app.DespotT1FitFig, 'S / sin(α)')
            app.DespotT1FitFig.XLim = [0 100];
            app.DespotT1FitFig.XTick = [0 10 20 30 40 50 60 70 80 90 100];
            app.DespotT1FitFig.YTick = [];
            app.T1map = uiaxes(app.FitResultsTab);
            app.T1map.XTick = [];
            app.T1map.YTick = [];
            app.M0map = uiaxes(app.FitResultsTab);
            app.M0map.XTick = [];
            app.M0map.YTick = [];
            app.FitImageSelectionPanel = uipanel(app.FitResultsTab);
            app.FitImageSelectionPanel.Title = 'Image selection';
            app.FitSliceEditField = uieditfield(app.FitImageSelectionPanel, 'numeric');
            app.FitSliceEditField.Limits = [1 256];
            app.FitSliceEditField.ValueDisplayFormat = '%.0f';
            app.FitSliceEditField.ValueChangedFcn = createCallbackFcn(app, @FitSliceEditFieldValueChanged, true);
            app.FitSliceEditField.Tooltip = {'Slice number'};
            app.FitSliceEditField.Value = 1;
            app.FitSliceSlider = uislider(app.FitImageSelectionPanel);
            app.FitSliceSlider.Limits = [1 256];
            app.FitSliceSlider.MajorTicks = [];
            app.FitSliceSlider.ValueChangedFcn = createCallbackFcn(app, @FitSliceSliderValueChanged, true);
            app.FitSliceSlider.MinorTicks = [1 16 31 46 61 76 91 106 121 136 151 166 181 196 211 226 241 256];
            app.FitSliceSlider.Tooltip = {'Slice number slider'};
            app.FitSliceSlider.Value = 1;
            app.FitFrameEditField = uieditfield(app.FitImageSelectionPanel, 'numeric');
            app.FitFrameEditField.Limits = [1 256];
            app.FitFrameEditField.ValueDisplayFormat = '%.0f';
            app.FitFrameEditField.ValueChangedFcn = createCallbackFcn(app, @FitFrameEditFieldValueChanged, true);
            app.FitFrameEditField.Tooltip = {'Frame number'};
            app.FitFrameEditField.Value = 1;
            app.FitFrameSlider = uislider(app.FitImageSelectionPanel);
            app.FitFrameSlider.Limits = [1 256];
            app.FitFrameSlider.MajorTicks = [];
            app.FitFrameSlider.ValueChangedFcn = createCallbackFcn(app, @FitFrameSliderValueChanged, true);
            app.FitFrameSlider.MinorTicks = [1 16 31 46 61 76 91 106 121 136 151 166 181 196 211 226 241 256];
            app.FitFrameSlider.Tooltip = {'Frame number slider'};
            app.FitFrameSlider.Value = 1;
            app.FitOrientationSpinner = uispinner(app.FitImageSelectionPanel);
            app.FitOrientationSpinner.Limits = [1 3];
            app.FitOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @FitOrientationSpinnerValueChanged, true);
            app.FitOrientationSpinner.Tooltip = {'Image orientation'};
            app.FitOrientationSpinner.Value = 1;
            app.FitSliceEditFieldLabel = uilabel(app.FitImageSelectionPanel);
            app.FitSliceEditFieldLabel.Text = 'Slice';
            app.FitFrameEditFieldLabel = uilabel(app.FitImageSelectionPanel);
            app.FitFrameEditFieldLabel.Text = 'frame';
            app.FitOrientationSpinnerLabel = uilabel(app.FitImageSelectionPanel);
            app.FitOrientationSpinnerLabel.Text = 'Orientation';
            app.ColormapButtonGroup = uibuttongroup(app.FitResultsTab);
            app.ColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ColormapButtonGroupSelectionChanged, true);
            app.ColormapButtonGroup.Title = 'Color map';
            app.grayButton = uiradiobutton(app.ColormapButtonGroup);
            app.grayButton.Text = 'gray';
            app.grayButton.Interpreter = 'html';
            app.autumnButton = uiradiobutton(app.ColormapButtonGroup);
            app.autumnButton.Text = 'autumn';
            app.autumnButton.Interpreter = 'html';
            app.winterButton = uiradiobutton(app.ColormapButtonGroup);
            app.winterButton.Text = 'winter';
            app.winterButton.Interpreter = 'html';
            app.parulaButton = uiradiobutton(app.ColormapButtonGroup);
            app.parulaButton.Text = 'parula';
            app.parulaButton.Interpreter = 'html';
            app.lipariButton = uiradiobutton(app.ColormapButtonGroup);
            app.lipariButton.Text = 'lipari';
            app.lipariButton.Interpreter = 'html';
            app.lipariButton.Value = true;
            app.MaxT1EditField = uieditfield(app.ColormapButtonGroup, 'numeric');
            app.MaxT1EditField.Limits = [1 5000];
            app.MaxT1EditField.ValueDisplayFormat = '%.0f';
            app.MaxT1EditField.ValueChangedFcn = createCallbackFcn(app, @MaxT1EditFieldValueChanged, true);
            app.MaxT1EditField.Tooltip = {'Max T1 scale'};
            app.MaxT1EditField.Value = 2000;
            app.MaxT1EditFieldLabel = uilabel(app.ColormapButtonGroup);
            app.MaxT1EditFieldLabel.Text = 'Max (ms)';
            app.AutoButton = uibutton(app.ColormapButtonGroup, 'push');
            app.AutoButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoButton.Tooltip = {'Automatic scaling of T1 map'};
            app.AutoButton.Text = 'Auto';
            app.ROIPanel = uipanel(app.FitResultsTab);
            app.ROIPanel.Title = 'ROI';
            app.EraseButton = uibutton(app.ROIPanel, 'push');
            app.EraseButton.ButtonPushedFcn = createCallbackFcn(app, @ROIEraseButtonPushed, true);
            app.EraseButton.Enable = 'off';
            app.EraseButton.Tooltip = {'Erase ROI'};
            app.EraseButton.Text = 'Erase';
            app.ROIbutton = uibutton(app.ROIPanel, 'push');
            app.ROIbutton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ROIbutton.Tooltip = {'Cross-hair / ROI drawing'};
            app.ROIbutton.Text = 'ROI';
            app.BrushEditField = uieditfield(app.ROIPanel, 'numeric');
            app.BrushEditField.Limits = [1 5];
            app.BrushEditField.ValueDisplayFormat = '%.0f';
            app.BrushEditField.Enable = 'off';
            app.BrushEditField.Tooltip = {'ROI brush size'};
            app.BrushEditField.Value = 2;
            app.BrushEditFieldLabel = uilabel(app.ROIPanel);
            app.BrushEditFieldLabel.Enable = 'off';
            app.BrushEditFieldLabel.Text = 'brush';
            app.M0image = uiimage(app.FitResultsTab);
            app.M0image.ImageClickedFcn = createCallbackFcn(app, @T1M0mapImageClicked, true);
            app.T1image = uiimage(app.FitResultsTab);
            app.T1image.ImageClickedFcn = createCallbackFcn(app, @T1M0mapImageClicked, true);
            app.M0YLabel = uilabel(app.FitResultsTab);
            app.M0YLabel.Text = 'Y';
            app.M0XLabel = uilabel(app.FitResultsTab);
            app.M0XLabel.Text = 'X';
            app.T1YLabel = uilabel(app.FitResultsTab);
            app.T1YLabel.Text = 'Y';
            app.T1XLabel = uilabel(app.FitResultsTab);
            app.T1XLabel.Text = 'X';
            app.M0Label = uilabel(app.FitResultsTab);
            app.M0Label.Text = 'M0';
            app.T1Label = uilabel(app.FitResultsTab);
            app.T1Label.Text = 'T1';
            app.SignalFunctionImage = uiimage(app.FitResultsTab);
            app.SignalFunctionImage.Tooltip = {'DESPOT1 fiting equation'};
            app.SignalFlipAngleLabel = uilabel(app.FitResultsTab);
            app.SignalFlipAngleLabel.Text = 'Signal - flip-angle';
            app.DESPOT1Label = uilabel(app.FitResultsTab);
            app.DESPOT1Label.Text = 'DESPOT1';
            app.FittedT1Label = uilabel(app.FitResultsTab);
            app.FittedT1Label.Text = 'T1';
            app.FitEquationLabel = uilabel(app.FitResultsTab);
            app.FitEquationLabel.Text = 'Fit equation';
            app.ECVTab = uitab(app.FitResultsTabGroup);
            app.ECVTab.Title = 'ECV';
            app.PreGdmap = uiaxes(app.ECVTab);
            app.PreGdmap.XTick = [];
            app.PreGdmap.YTick = [];
            app.PostGdmap = uiaxes(app.ECVTab);
            app.PostGdmap.XTick = [];
            app.PostGdmap.YTick = [];
            app.ECVmap = uiaxes(app.ECVTab);
            app.ECVmap.XTick = [];
            app.ECVmap.YTick = [];
            app.PostGdLabel = uilabel(app.ECVTab);
            app.PostGdLabel.Text = 'Post Gd';
            app.PostGdYLabel = uilabel(app.ECVTab);
            app.PostGdYLabel.Text = 'Y';
            app.PostGdXLabel = uilabel(app.ECVTab);
            app.PostGdXLabel.Text = 'X';
            app.HematocritEditFieldLabel = uilabel(app.ECVTab);
            app.HematocritEditFieldLabel.Tooltip = {'Hematocrit value (%)'};
            app.HematocritEditFieldLabel.Text = 'Hematocrit (%)';
            app.HematocritEditField = uieditfield(app.ECVTab, 'numeric');
            app.HematocritEditField.Limits = [1 99];
            app.HematocritEditField.ValueDisplayFormat = '%.1f';
            app.HematocritEditField.ValueChangedFcn = createCallbackFcn(app, @ECVBloodParametersEditFieldValueChanged, true);
            app.HematocritEditField.Tooltip = {'Hematocrit value (%)'};
            app.HematocritEditField.Value = 40;
            app.PreGdLabel = uilabel(app.ECVTab);
            app.PreGdLabel.Text = 'Pre Gd';
            app.PreGdYLabel = uilabel(app.ECVTab);
            app.PreGdYLabel.Text = 'Y';
            app.PreGdXLabel = uilabel(app.ECVTab);
            app.PreGdXLabel.Text = 'X';
            app.ECVLabel = uilabel(app.ECVTab);
            app.ECVLabel.Text = 'ECV';
            app.ECVXLabel = uilabel(app.ECVTab);
            app.ECVXLabel.Text = 'X';
            app.ECVYLabel = uilabel(app.ECVTab);
            app.ECVYLabel.Text = 'Y';
            app.ECVBloodROIPanel = uipanel(app.ECVTab);
            app.ECVBloodROIPanel.Title = 'Blood ROI';
            app.ECVEraseButton = uibutton(app.ECVBloodROIPanel, 'push');
            app.ECVEraseButton.ButtonPushedFcn = createCallbackFcn(app, @ECVEraseButtonPushed, true);
            app.ECVEraseButton.Tooltip = {'Erase ROI'};
            app.ECVEraseButton.Text = 'Erase';
            app.ECVBrushEditField = uieditfield(app.ECVBloodROIPanel, 'numeric');
            app.ECVBrushEditField.Limits = [1 5];
            app.ECVBrushEditField.ValueDisplayFormat = '%.0f';
            app.ECVBrushEditField.Tooltip = {'ROI brush size'};
            app.ECVBrushEditField.Value = 2;
            app.ECVBrushEditFieldLabel = uilabel(app.ECVBloodROIPanel);
            app.ECVBrushEditFieldLabel.Text = 'brush';
            app.ECVColormapButtonGroup = uibuttongroup(app.ECVTab);
            app.ECVColormapButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ECVColormapButtonGroupSelectionChanged, true);
            app.ECVColormapButtonGroup.Title = 'Color map';
            app.ECVgrayButton = uiradiobutton(app.ECVColormapButtonGroup);
            app.ECVgrayButton.Text = 'gray';
            app.ECVgrayButton.Interpreter = 'html';
            app.ECVautumnButton = uiradiobutton(app.ECVColormapButtonGroup);
            app.ECVautumnButton.Text = 'autumn';
            app.ECVautumnButton.Interpreter = 'html';
            app.ECVwinterButton = uiradiobutton(app.ECVColormapButtonGroup);
            app.ECVwinterButton.Text = 'winter';
            app.ECVwinterButton.Interpreter = 'html';
            app.ECVparulaButton = uiradiobutton(app.ECVColormapButtonGroup);
            app.ECVparulaButton.Text = 'parula';
            app.ECVparulaButton.Interpreter = 'html';
            app.ECVlipariButton = uiradiobutton(app.ECVColormapButtonGroup);
            app.ECVlipariButton.Text = 'lipari';
            app.ECVlipariButton.Interpreter = 'html';
            app.ECVlipariButton.Value = true;
            app.ECVImageSelectionPanel = uipanel(app.ECVTab);
            app.ECVImageSelectionPanel.Title = 'Image selection';
            app.ECVSliceEditField = uieditfield(app.ECVImageSelectionPanel, 'numeric');
            app.ECVSliceEditField.Limits = [1 256];
            app.ECVSliceEditField.ValueDisplayFormat = '%.0f';
            app.ECVSliceEditField.ValueChangedFcn = createCallbackFcn(app, @ECVSliceEditFieldValueChanged, true);
            app.ECVSliceEditField.Tooltip = {'Slice number'};
            app.ECVSliceEditField.Value = 1;
            app.ECVSliceSlider = uislider(app.ECVImageSelectionPanel);
            app.ECVSliceSlider.Limits = [1 256];
            app.ECVSliceSlider.MajorTicks = [];
            app.ECVSliceSlider.ValueChangedFcn = createCallbackFcn(app, @ECVSliceSliderValueChanged, true);
            app.ECVSliceSlider.MinorTicks = [1 16 31 46 61 76 91 106 121 136 151 166 181 196 211 226 241 256];
            app.ECVSliceSlider.Tooltip = {'Slice number slider'};
            app.ECVSliceSlider.Value = 1;
            app.ECVFrameEditField = uieditfield(app.ECVImageSelectionPanel, 'numeric');
            app.ECVFrameEditField.Limits = [1 256];
            app.ECVFrameEditField.ValueDisplayFormat = '%.0f';
            app.ECVFrameEditField.ValueChangedFcn = createCallbackFcn(app, @ECVFrameEditFieldValueChanged, true);
            app.ECVFrameEditField.Tooltip = {'Frame number'};
            app.ECVFrameEditField.Value = 1;
            app.ECVFrameSlider = uislider(app.ECVImageSelectionPanel);
            app.ECVFrameSlider.Limits = [1 256];
            app.ECVFrameSlider.MajorTicks = [];
            app.ECVFrameSlider.ValueChangedFcn = createCallbackFcn(app, @ECVFrameSliderValueChanged, true);
            app.ECVFrameSlider.MinorTicks = [1 16 31 46 61 76 91 106 121 136 151 166 181 196 211 226 241 256];
            app.ECVFrameSlider.Tooltip = {'Frame number slider'};
            app.ECVFrameSlider.Value = 1;
            app.ECVOrientationSpinner = uispinner(app.ECVImageSelectionPanel);
            app.ECVOrientationSpinner.Limits = [1 3];
            app.ECVOrientationSpinner.ValueChangedFcn = createCallbackFcn(app, @ECVOrientationSpinnerValueChanged, true);
            app.ECVOrientationSpinner.Tooltip = {'Image orientation'};
            app.ECVOrientationSpinner.Value = 1;
            app.ECVOrientationSpinnerLabel = uilabel(app.ECVImageSelectionPanel);
            app.ECVOrientationSpinnerLabel.Text = 'Orientation';
            app.ECVSliceEditFieldLabel = uilabel(app.ECVImageSelectionPanel);
            app.ECVSliceEditFieldLabel.Text = 'Slice';
            app.ECVFrameEditFieldLabel = uilabel(app.ECVImageSelectionPanel);
            app.ECVFrameEditFieldLabel.Text = 'frame';
            app.ECVLoadPreGdButton = uibutton(app.ECVTab, 'push');
            app.ECVLoadPreGdButton.ButtonPushedFcn = createCallbackFcn(app, @ECVECVLoadPreGdButtonPushed, true);
            app.ECVLoadPreGdButton.Tooltip = {'Load pre Gd contrast Nifti images'};
            app.ECVLoadPreGdButton.Text = 'Load Pre';
            app.ECVLoadPostGdButton = uibutton(app.ECVTab, 'push');
            app.ECVLoadPostGdButton.ButtonPushedFcn = createCallbackFcn(app, @ECVECVLoadPostGdButtonPushed, true);
            app.ECVLoadPostGdButton.Tooltip = {'Load post Gd contrast Nifti images'};
            app.ECVLoadPostGdButton.Text = 'Load Post';
            app.ECVRegisterButton = uibutton(app.ECVTab, 'push');
            app.ECVRegisterButton.ButtonPushedFcn = createCallbackFcn(app, @ECVRegisterButtonPushed, true);
            app.ECVRegisterButton.Tooltip = {'Register post Gd contrast to pre Gd contrast images'};
            app.ECVRegisterButton.Text = 'Register';
            app.ECVRegistrationDropDown = uidropdown(app.ECVTab);
            app.ECVRegistrationDropDown.Items = {'Translation', 'Rigid', 'Affine', 'B-Spline'};
            app.ECVRegistrationDropDown.Tooltip = {'Registration transformation method'};
            app.ECVRegistrationDropDown.Value = 'Affine';
            app.ECVExportButton = uibutton(app.ECVTab, 'push');
            app.ECVExportButton.ButtonPushedFcn = createCallbackFcn(app, @ECVExportNiftiButtonPushed, true);
            app.ECVExportButton.Tooltip = {'Export to Nifti'};
            app.ECVExportButton.Text = 'Export ECV';
            app.PreGdTransImage = uiimage(app.ECVTab);
            app.PreGdTransImage.ImageClickedFcn = createCallbackFcn(app, @ECVPrePostGdImageClicked, true);
            app.PostGdTransImage = uiimage(app.ECVTab);
            app.PostGdTransImage.ImageClickedFcn = createCallbackFcn(app, @ECVPrePostGdImageClicked, true);
            app.ECVTransImage = uiimage(app.ECVTab);
            app.T1preBloodEditFieldLabel = uilabel(app.ECVTab);
            app.T1preBloodEditFieldLabel.Tooltip = {'Pre Gd contrast blood T1 value (ms)'};
            app.T1preBloodEditFieldLabel.Text = 'T1 pre blood (ms)';
            app.T1preBloodEditField = uieditfield(app.ECVTab, 'numeric');
            app.T1preBloodEditField.Limits = [1 5000];
            app.T1preBloodEditField.ValueDisplayFormat = '%.1f';
            app.T1preBloodEditField.ValueChangedFcn = createCallbackFcn(app, @ECVBloodParametersEditFieldValueChanged, true);
            app.T1preBloodEditField.Tooltip = {'Pre Gd contrast blood T1 value (ms)'};
            app.T1preBloodEditField.Value = 2200;
            app.T1postBloodEditFieldLabel = uilabel(app.ECVTab);
            app.T1postBloodEditFieldLabel.Tooltip = {'Post Gd contrast blood T1 value (ms)'};
            app.T1postBloodEditFieldLabel.Text = 'T1 post blood (ms)';
            app.T1postBloodEditField = uieditfield(app.ECVTab, 'numeric');
            app.T1postBloodEditField.Limits = [1 5000];
            app.T1postBloodEditField.ValueDisplayFormat = '%.1f';
            app.T1postBloodEditField.ValueChangedFcn = createCallbackFcn(app, @ECVBloodParametersEditFieldValueChanged, true);
            app.T1postBloodEditField.Tooltip = {'Post Gd contrast blood T1 value (ms)'};
            app.T1postBloodEditField.Value = 600;
            app.Hyperlink = uihyperlink(app.T1mappUIFigure);
            app.Hyperlink.URL = 'https://github.com/Moby1971/';
            app.Hyperlink.Text = 'Github';
            % Show the figure after all components are created
            app.T1mappUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = T1mappD

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.T1mappUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.T1mappUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.T1mappUIFigure)
        end
    end
end